classdef BeamGeometry_v_1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectXrayBeamGeometryUIFigure  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        BeamGeometryDropDownLabel       matlab.ui.control.Label
        BeamGeometryDropDown            matlab.ui.control.DropDown
        OKButton                        matlab.ui.control.StateButton
        CancelButton                    matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        geom
        beamgeom
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulatorCTlab)
            app.SelectXrayBeamGeometryUIFigure.Position = [690 500 432 199];
            app.geom = simulatorCTlab;
            %            app.beamgeom = 'Parallel';
            gpu = gpuDeviceCount;
            
            if gpu == 0
                app.BeamGeometryDropDown.Items = {'Parallel'};
                message = {'Local GPU not found, parallel beam geometry only in use...'};
                uialert(app.SelectXrayBeamGeometryUIFigure,message,'Warning',...
                    'Icon','warning');
            else
                app.BeamGeometryDropDown.Items = {'Parallel', 'Fanflat'};
            end
            
%             app.BeamGeometryDropDown.Items = {'Parallel', 'Fanflat'};
        end

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            ChosenBeamgeom(app.geom, app.BeamGeometryDropDown.Value);
            
            delete(app)
        end

        % Close request function: SelectXrayBeamGeometryUIFigure
        function SelectXrayBeamGeometryUIFigureCloseRequest(app, event)
            
            CancelBeamgeomSelection(app.geom)
            delete(app)
        end

        % Value changed function: CancelButton
        function CancelButtonValueChanged(app, event)
            
            CancelBeamgeomSelection(app.geom)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectXrayBeamGeometryUIFigure = uifigure('Visible', 'off');
            app.SelectXrayBeamGeometryUIFigure.Name = 'Select X-ray Beam Geometry';
            app.SelectXrayBeamGeometryUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectXrayBeamGeometryUIFigureCloseRequest, true);
            app.Panel = uipanel(app.SelectXrayBeamGeometryUIFigure);
            app.BeamGeometryDropDownLabel = uilabel(app.SelectXrayBeamGeometryUIFigure);
            app.BeamGeometryDropDownLabel.Text = 'Beam Geometry:';
            app.BeamGeometryDropDown = uidropdown(app.SelectXrayBeamGeometryUIFigure);
            app.BeamGeometryDropDown.Items = {'Parallel', 'Fanflat'};
            app.BeamGeometryDropDown.ItemsData = {'Parallel', 'Fanflat'};
            app.BeamGeometryDropDown.Value = 'Parallel';
            app.OKButton = uibutton(app.SelectXrayBeamGeometryUIFigure, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.SelectXrayBeamGeometryUIFigure, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @CancelButtonValueChanged, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SelectXrayBeamGeometryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamGeometry_v_1_4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectXrayBeamGeometryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectXrayBeamGeometryUIFigure)
        end
    end
end