classdef ImagingParameters_v_1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectScanParametersUIFigure    matlab.ui.Figure
        ParameterselectionPanel         matlab.ui.container.Panel
        MinangleEditFieldLabel          matlab.ui.control.Label
        MinangleEditField               matlab.ui.control.NumericEditField
        StepangleEditFieldLabel         matlab.ui.control.Label
        StepangleEditField              matlab.ui.control.NumericEditField
        MaxangleEditFieldLabel          matlab.ui.control.Label
        MaxangleEditField               matlab.ui.control.NumericEditField
        ImagevolumepixEditFieldLabel    matlab.ui.control.Label
        ImagevolumepixEditField         matlab.ui.control.NumericEditField
        DetectorwidthpixEditFieldLabel  matlab.ui.control.Label
        DetectorwidthpixEditField       matlab.ui.control.NumericEditField
        DeterctorelementsizepixEditFieldLabel  matlab.ui.control.Label
        DeterctorelementsizepixEditField  matlab.ui.control.NumericEditField
        SODEditFieldLabel               matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        ScantimesEditField              matlab.ui.control.NumericEditField
        RegularizationparameterEditField  matlab.ui.control.NumericEditField
        IterationsEditField             matlab.ui.control.NumericEditField
        SODEditField                    matlab.ui.control.NumericEditField
        ODDEditFieldLabel               matlab.ui.control.Label
        ODDEditField                    matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        ScantimesEditFieldLabel         matlab.ui.control.Label
        IterationsEditFieldLabel        matlab.ui.control.Label
        RegularizationparameterEditFieldLabel  matlab.ui.control.Label
        GradientdescentstepsizeEditFieldLabel  matlab.ui.control.Label
        GradientdescentstepsizeEditField  matlab.ui.control.NumericEditField
        RunscanpreviewButton            matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        ScanpreviewPanel                matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        ParametervisualizationPanel     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp;
        rec;
        beam;
        CurrentBeamgeom; CurrentReco;
        max;
        min;
        step;
        imgvol;
        det_width;
        det_el_size;
        sod;
        odd;
        noise;
        iterations;
        gradient;
        scantime;
        reg_param;
        mA;
    end
    
    methods (Access = public)
        
        function tubeCurr(app,mA)
            app.mAEditField.Value=mA;
        end
        
        function ImagingparamLayout(app,reconstruct,beamgeometr)
            %Muuttaa imagingParameters-appin parametreja valitun
            %rekonstruktiogeometrian ja -algoritmin mukaan
            %             app.rec=reconstruct;
            %             app.beam=beamgeometr;
            app.rec=reconstruct;
            app.beam=beamgeometr;
            
            if strcmp('Filtered backprojection (FBP)',app.rec)
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Visible = 'off';
                app.RegularizationparameterEditField.Visible = 'off';
                app.RegularizationparameterEditFieldLabel.Visible='off';
                app.GradientdescentstepsizeEditField.Visible = 'off';
                app.GradientdescentstepsizeEditFieldLabel.Visible='off';
                if strcmp('Parallel',app.beam)
                    app.SODEditField.Visible = 'off';
                    app.SODEditFieldLabel.Visible='off';
                    app.ODDEditField.Visible = 'off';
                    app.ODDEditFieldLabel.Visible='off';
                end
            end
            
            if strcmp('Least squares',app.rec)
                app.RegularizationparameterEditField.Visible = 'off';
                app.RegularizationparameterEditFieldLabel.Visible='off';
                app.GradientdescentstepsizeEditField.Visible = 'off';
                app.GradientdescentstepsizeEditFieldLabel.Visible='off';
                if strcmp('Parallel',app.beam)
                    app.SODEditField.Visible = 'off';
                    app.SODEditFieldLabel.Visible='off';
                    app.ODDEditField.Visible = 'off';
                    app.ODDEditFieldLabel.Visible='off';
                end
            end
            
            if strcmp('Tikhonov Regularization',app.rec)
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Visible='off';
                app.GradientdescentstepsizeEditField.Visible = 'off';
                app.GradientdescentstepsizeEditFieldLabel.Visible='off';
                if strcmp('Parallel',app.beam)
                    app.SODEditField.Visible = 'off';
                    app.SODEditFieldLabel.Visible='off';
                    app.ODDEditField.Visible = 'off';
                    app.ODDEditFieldLabel.Visible='off';
                end
            end
            
            if strcmp('Gradient descent',app.rec)
                app.RegularizationparameterEditField.Visible = 'off';
                app.RegularizationparameterEditFieldLabel.Visible='off';
                if strcmp('Parallel',app.beam)
                    app.SODEditField.Visible = 'off';
                    app.SODEditFieldLabel.Visible='off';
                    app.ODDEditField.Visible = 'off';
                    app.ODDEditFieldLabel.Visible='off';
                end
            end
        end
        
        %
        function GeomReco(app,reconstruct,beamgeometr)
            app.CurrentReco = reconstruct;
            app.CurrentBeamgeom=beamgeometr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulatorCTlab, CurrentBeamgeom, CurrentReco, mA)
            app.SelectScanParametersUIFigure.Position = [430 200 1050,695];
            app.CallingApp = simulatorCTlab;
            
            I=phantom(256);
            set(app.UIAxes4,'XLim',[-150,150],'XDir','reverse')%,'XtickMode','MANUAL','XtickLabelMode','MANUAL','Xticklabel',[-150,-100,-50,0,50,100,150]);
            set(app.UIAxes4,'YLim',[-150,150],'YDir','reverse')%,'YtickMode','MANUAL','YtickLabelMode','MANUAL','Yticklabel',[150,100,50,0,-50,-100,-150]);
            set(app.UIAxes4,'Color',[0 0 0],'TickDir','out');
            
            imshow(I,'Parent',app.UIAxes4,'XData',[-70 70],'YData',[-70 70]);
            
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes2.Toolbar.Visible = 'off';
            app.UIAxes3.Toolbar.Visible = 'off';
            app.UIAxes4.Toolbar.Visible = 'off';
            
            %             app.max = app.MaxangleEditField.Value;
            %             app.min = app.MinangleEditField.Value;
            %             app.step = app.StepangleEditField.Value;
            %             app.imgvol = app.ImagevolumepixEditField.Value;
            %             app.det_width = app.DetectorwidthpixEditField.Value;
            %             app.det_el_size = app.DeterctorelementsizepixEditField.Value;
            %             app.sod = app.SODEditField.Value;
            %             app.odd = app.ODDEditField.Value;
            %             app.noise = app.NoiseEditField.Value;
            %             app.iterations = app.IterationsEditField.Value;
            %             app.gradient = app.GradientdescentstepsizeEditField.Value;
            %             app.scantime = app.ScantimesEditField.Value;
            %             app.reg_param = app.RegularizationparameterEditField.Value;
            
        end

        % Value changed function: MaxangleEditField
        function MaxangleEditFieldValueChanged(app, event)
            
            %             t=app.MinangleEditField.Value:app.StepangleEditField.Value:app.MaxangleEditField.Value;
            t=app.MinangleEditField.Value:5:app.MaxangleEditField.Value;
            sine=sind(t);
            cosine=cosd(t);
            plot(app.UIAxes,sine(1:1:end),cosine(1:1:end),'w.');
            set(app.UIAxes,'XLim',[-1.5,1.5],'XDir','reverse') % ,'XtickMode','MANUAL','XtickLabelMode','MANUAL','Xticklabel',[-1.5,-1,-0.5,0,0.5,1,1.5]);
            set(app.UIAxes,'YLim',[-1.5,1.5],'YDir','reverse') % ,'YtickMode','MANUAL','YtickLabelMode','MANUAL','Yticklabel',[1.5,1,0.5,0,-0.5,-1,-1.5]);
            set(app.UIAxes,'Color',[0 0 0]);
            
        end

        % Value changed function: DetectorwidthpixEditField
        function DetectorwidthpixEditFieldValueChanged(app, event)
            
            app.imgvol = app.ImagevolumepixEditField.Value;
            app.det_width = app.DetectorwidthpixEditField.Value;
            
            
            I = phantom(app.imgvol);
            
            set(app.UIAxes2,'XLim',[-150,150],'XDir','reverse')%,'XtickMode','MANUAL','XtickLabelMode','MANUAL','Xticklabel',[-150,-100,-50,0,50,100,150]);
            set(app.UIAxes2,'YLim',[-150,150],'YDir','reverse')%,'YtickMode','MANUAL','YtickLabelMode','MANUAL','Yticklabel',[150,100,50,0,-50,-100,-150]);
            set(app.UIAxes2,'Color',[0 0 0],'TickDir','out');
            imshow(I,'Parent',app.UIAxes2,'XData',[-70 70],'YData',[-70 70]);
            
            %Scale factor
            F= 150/app.imgvol;
            
            t1 = hgtransform('Parent',app.UIAxes2);
            
            rectangle('Parent',t1,'Position',[70, -F*app.det_width/2, 20, F*app.det_width],'EdgeColor',[1 1 1]);
            polyin = polyshape([-70 -70 70 70], [F*app.det_width/2 -F*app.det_width/2 -F*app.det_width/2 F*app.det_width/2]);
            plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            
            %             if app.det_width > app.imgvol
            %                 t1 = hgtransform('Parent',app.UIAxes2);
            %
            %                 if strcmp('Parallel',app.beam)
            %                     rectangle('Parent',t1,'Position',[-75,-90,150,20],'EdgeColor',[1 1 1]);
            %                     polyin = polyshape([-75 -75 75 75], [70 -70 -70 70]);
            %                     plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            %
            %                 elseif strcmp('Fanflat',app.beam)
            %                     rectangle('Parent',t1,'Position',[-75,-170,150,20],'EdgeColor',[1 1 1]);
            %                     polyin = polyshape([-120 0 120],[70 -150 70]);
            %                     plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            %                 end
            %                 t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
            %             elseif app.det_width == app.imgvol
            %                 t1 = hgtransform('Parent',app.UIAxes2);
            %
            %                 if strcmp('Parallel',app.beam)
            %                     rectangle('Parent',t1,'Position',[-70,-90,140,20],'EdgeColor',[1 1 1]);
            %                     polyin = polyshape([-70 -70 70 70], [70 -70 -70 70]);
            %                     plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            %
            %                 elseif strcmp('Fanflat',app.beam)
            %                     rectangle('Parent',t1,'Position',[-75,-170,150,20],'EdgeColor',[1 1 1]);
            %                     polyin = polyshape([-70 0 70],[70 -150 70]);
            %                     plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            %                 end
            %                 t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
            %             elseif app.det_width < app.imgvol
            %                 t1 = hgtransform('Parent',app.UIAxes2);
            %
            %                 if strcmp('Parallel',app.beam)
            %                     rectangle('Parent',t1,'Position',[-app.det_width/2,-90,app.det_width,20],'EdgeColor',[1 1 1]);
            %                     polyin = polyshape([-app.det_width/2 -app.det_width/2 app.det_width/2 app.det_width/2], [70 -70 -70 70]);
            %                     plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            %
            %                 elseif strcmp('Fanflat',app.beam)
            %                     polyin = polyshape([sind(-1)*100-(app.det_width/2) sind(-1)*100+(app.det_width/2) sind(-1)*100],[cosd(0)*100 cosd(0)*100 cosd(0)-110]);
            %                     plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
            %                 end
            %                 t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
            %             end
            %
        end

        % Value changed function: NoiseEditField
        function NoiseEditFieldValueChanged(app, event)
            
            set(app.UIAxes3,'XLim',[-150,150],'YLim',[-150,150],'Color',[0 0 0]);
            I = imnoise(phantom(256),'gaussian',app.NoiseEditField.Value,0.05);
            imshow(I,'Parent',app.UIAxes3,'XData',[-150 150],'YData',[-150 150]);
            
        end

        % Button pushed function: RunscanpreviewButton
        function RunscanpreviewButtonPushed(app, event)
            %Read editfield values and store them into variables
            app.max = app.MaxangleEditField.Value;
            app.min = app.MinangleEditField.Value;
            app.step = app.StepangleEditField.Value;
            app.imgvol = app.ImagevolumepixEditField.Value;
            app.det_width = app.DetectorwidthpixEditField.Value;
            app.det_el_size = app.DeterctorelementsizepixEditField.Value;
            app.sod = app.SODEditField.Value;
            app.odd = app.ODDEditField.Value;
            app.noise = app.NoiseEditField.Value;
            app.iterations = app.IterationsEditField.Value;
            app.gradient = app.GradientdescentstepsizeEditField.Value;
            app.scantime = app.ScantimesEditField.Value;
            app.reg_param = app.RegularizationparameterEditField.Value;
            
            %             oldData = get(app.UITable,'Data'); %Read old tabledata into variable oldData
            %             newData = {app.min,app.step,app.max,app.imgvol,app.det_width,app.det_el_size,app.sod,app.odd,app.noise,app.iterations,app.gradient,app.scantime,app.reg_param,app.mA}; %Store last editfield values into variable newData
            %             app.UITable.Data =[newData;oldData]; %Add newData at the first row of table
            %             s1 = uistyle; %Change tables first rows style into bold and darker grey
            %             s1.FontWeight = 'Bold';
            %             s1.BackgroundColor = [0.8 0.8 0.8];
            %             addStyle(app.UITable, s1, 'row', 1);
            
            I = phantom(app.imgvol);
            
            for angle = app.min:10:app.max
                
                set(app.UIAxes4,'XLim',[-150,150],'XDir','reverse')%,'XtickMode','MANUAL','XtickLabelMode','MANUAL','Xticklabel',[-150,-100,-50,0,50,100,150]);
                set(app.UIAxes4,'YLim',[-150,150],'YDir','reverse')%,'YtickMode','MANUAL','YtickLabelMode','MANUAL','Yticklabel',[150,100,50,0,-50,-100,-150]);
                set(app.UIAxes4,'Color',[0 0 0]);
                imshow(I,'Parent',app.UIAxes4,'XData',[-70 70],'YData',[-70 70]);
                
                t1 = hgtransform('Parent',app.UIAxes4);
                F= 150/app.imgvol;
                
                rectangle('Parent',t1,'Position',[70, -F*app.det_width/2, 20, F*app.det_width],'EdgeColor',[1 1 1]);
                polyin = polyshape([-70 -70 70 70], [F*app.det_width/2 -F*app.det_width/2 -F*app.det_width/2 F*app.det_width/2]);
                plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                
                t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
                
                %                 if app.det_width > app.imgvol
                %                     t1 = hgtransform('Parent',app.UIAxes4);
                %                     %                 t1.Parent.Xlim = [-detwidth/2 detwidth/2]
                %                     %                 r=rectangle('Parent',t1,'Position',[sind(-1)*100-(app.det_width/2),cosd(0)*100,app.det_width,app.det_width/10],'EdgeColor',[1 1 1]);
                %
                %                     if strcmp('Parallel',app.beam)
                %                         rectangle('Parent',t1,'Position',[-75,-90,150,20],'EdgeColor',[1 1 1]);
                %                         %                     polyin = polyshape([sind(-1)*100-(app.det_width/2) sind(-1)*100+(app.det_width/2) sind(-1)*100+(app.det_width/2) sind(-1)*100-(app.det_width/2)],[cosd(0)*100 cosd(0)*100 cosd(0)-100 cosd(0)*-100]);
                %                         polyin = polyshape([-75 -75 75 75], [70 -70 -70 70]);
                %                         plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                %
                %                     elseif strcmp('Fanflat',app.beam)
                %                         rectangle('Parent',t1,'Position',[-75,-170,150,20],'EdgeColor',[1 1 1]);
                %                         %                     polyin = polyshape([sind(-1)*100-(app.det_width/2) sind(-1)*100+(app.det_width/2) sind(-1)*100],[cosd(0)*100 cosd(0)*100 cosd(0)-110]);
                %                         polyin = polyshape([-120 0 120],[70 -150 70]);
                %                         plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                %                     end
                %                     t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
                %                 elseif app.det_width == app.imgvol
                %                     t1 = hgtransform('Parent',app.UIAxes4);
                %
                %                     if strcmp('Parallel',app.beam)
                %                         rectangle('Parent',t1,'Position',[-70,-90,140,20],'EdgeColor',[1 1 1]);
                %                         polyin = polyshape([-70 -70 70 70], [70 -70 -70 70]);
                %                         plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                %
                %                     elseif strcmp('Fanflat',app.beam)
                %                         rectangle('Parent',t1,'Position',[-75,-170,150,20],'EdgeColor',[1 1 1]);
                %                         polyin = polyshape([-70 0 70],[70 -150 70]);
                %                         plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                %                     end
                %                     t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
                %                 elseif app.det_width < app.imgvol
                %                     t1 = hgtransform('Parent',app.UIAxes4);
                %
                %                     if strcmp('Parallel',app.beam)
                %                         rectangle('Parent',t1,'Position',[-app.det_width/2,-90,app.det_width,20],'EdgeColor',[1 1 1]);
                %                         polyin = polyshape([-app.det_width/2 -app.det_width/2 app.det_width/2 app.det_width/2], [70 -70 -70 70]);
                %                         plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                %
                %                     elseif strcmp('Fanflat',app.beam)
                %                         polyin = polyshape([sind(-1)*100-(app.det_width/2) sind(-1)*100+(app.det_width/2) sind(-1)*100],[cosd(0)*100 cosd(0)*100 cosd(0)-110]);
                %                         plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                %                     end
                %                     t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
                %                 end
                
                pause(0.001);
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %Calls ChosenImagingParameters function from main app
            %(simulatorCTlab)
            
            ChosenImagingParameters(app.CallingApp,app.MaxangleEditField.Value,app.MinangleEditField.Value,app.StepangleEditField.Value,...
                app.ImagevolumepixEditField.Value,app.DetectorwidthpixEditField.Value,app.DeterctorelementsizepixEditField.Value,app.SODEditField.Value,...
                app.ODDEditField.Value,app.NoiseEditField.Value,app.IterationsEditField.Value,app.GradientdescentstepsizeEditField.Value,...
                app.ScantimesEditField.Value,app.RegularizationparameterEditField.Value,app.CurrentBeamgeom,app.CurrentReco);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectScanParametersUIFigure = uifigure('Visible', 'off');
            app.SelectScanParametersUIFigure.Name = 'Select Scan Parameters';
            app.ParameterselectionPanel = uipanel(app.SelectScanParametersUIFigure);
            app.ParameterselectionPanel.Title = 'Parameter selection';
            app.MinangleEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.MinangleEditFieldLabel.Text = 'Min angle';
            app.MinangleEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.StepangleEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.StepangleEditFieldLabel.Text = 'Step angle';
            app.StepangleEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.MaxangleEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.MaxangleEditFieldLabel.Text = 'Max angle';
            app.MaxangleEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.MaxangleEditField.ValueChangedFcn = createCallbackFcn(app, @MaxangleEditFieldValueChanged, true);
            app.ImagevolumepixEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.ImagevolumepixEditFieldLabel.Text = 'Image volume (pix.)';
            app.ImagevolumepixEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.DetectorwidthpixEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.DetectorwidthpixEditFieldLabel.Text = 'Detector width (pix.)';
            app.DetectorwidthpixEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.DetectorwidthpixEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorwidthpixEditFieldValueChanged, true);
            app.DeterctorelementsizepixEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.DeterctorelementsizepixEditFieldLabel.Text = 'Deterctor element size (pix.)';
            app.DeterctorelementsizepixEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.SODEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.SODEditFieldLabel.Text = 'SOD';
            app.NoiseEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.ScantimesEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.RegularizationparameterEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.IterationsEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.SODEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.ODDEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.ODDEditFieldLabel.Text = 'ODD';
            app.ODDEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.NoiseEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.ScantimesEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.ScantimesEditFieldLabel.Text = 'Scan time (s)';
            app.IterationsEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.RegularizationparameterEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.RegularizationparameterEditFieldLabel.Text = 'Regularization parameter';
            app.GradientdescentstepsizeEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.GradientdescentstepsizeEditFieldLabel.Text = 'Gradient descent step size';
            app.GradientdescentstepsizeEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.RunscanpreviewButton = uibutton(app.ParameterselectionPanel, 'push');
            app.RunscanpreviewButton.ButtonPushedFcn = createCallbackFcn(app, @RunscanpreviewButtonPushed, true);
            app.RunscanpreviewButton.Text = 'Run scan preview';
            app.OKButton = uibutton(app.ParameterselectionPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ScanpreviewPanel = uipanel(app.SelectScanParametersUIFigure);
            app.ScanpreviewPanel.Title = 'Scan preview';
            app.Panel = uipanel(app.ScanpreviewPanel);
            app.UIAxes4 = uiaxes(app.Panel);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.ParametervisualizationPanel = uipanel(app.SelectScanParametersUIFigure);
            app.ParametervisualizationPanel.Title = 'Parameter visualization';
            app.UIAxes = uiaxes(app.ParametervisualizationPanel);
            title(app.UIAxes, 'Scan angles')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.ParametervisualizationPanel);
            title(app.UIAxes2, 'Detector')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.ParametervisualizationPanel);
            title(app.UIAxes3, 'Noise')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            % Show the figure after all components are created
            app.SelectScanParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagingParameters_v_1_4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectScanParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectScanParametersUIFigure)
        end
    end
end