classdef simulatorCTlab_1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CTlabComputedTomographySimulatorv14UIFigure  matlab.ui.Figure
        SIMULATIONCONTROLPANELPanel    matlab.ui.container.Panel
        Panel_8                        matlab.ui.container.Panel
        XRayspectrumPanel              matlab.ui.container.Panel
        LoadspectrumButton             matlab.ui.control.Button
        GeneratespectrumButton         matlab.ui.control.Button
        SetParametersPanel             matlab.ui.container.Panel
        ImagingparametersButton        matlab.ui.control.Button
        BeamgeometryButton             matlab.ui.control.Button
        PolychromaticreconstructionPanel  matlab.ui.container.Panel
        ReconstructionButton           matlab.ui.control.Button
        ReconstructionalgorithmButton  matlab.ui.control.Button
        ObtainprojectionsPanel         matlab.ui.container.Panel
        ScanButton                     matlab.ui.control.Button
        ImagedataPanel                 matlab.ui.container.Panel
        VisualizeprojectionsButton     matlab.ui.control.Button
        SpectralreconstructionPanel    matlab.ui.container.Panel
        SpectralparametersButton       matlab.ui.control.Button
        SpectralreconstructionButton   matlab.ui.control.Button
        LineprofilePanel               matlab.ui.container.Panel
        SelectdatapointsButton         matlab.ui.control.Button
        SelectspectralimagePanel       matlab.ui.container.Panel
        Spinner                        matlab.ui.control.Spinner
        BasicoperationsPanel           matlab.ui.container.Panel
        SaveimageButton                matlab.ui.control.Button
        ClearimageButton               matlab.ui.control.Button
        SelectimagePanel               matlab.ui.container.Panel
        LeftCheckBox                   matlab.ui.control.CheckBox
        RightCheckBox                  matlab.ui.control.CheckBox
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        STARTSIMULATIONPanel           matlab.ui.container.Panel
        LoadphantomButton              matlab.ui.control.Button
        UIAxes6                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        ParametersinfoListBoxLabel     matlab.ui.control.Label
        ParametersinfoListBox          matlab.ui.control.ListBox
        Panel_2                        matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        VisualizationinfoListBoxLabel  matlab.ui.control.Label
        VisualizationinfoListBox       matlab.ui.control.ListBox
        ContrastPanel                  matlab.ui.container.Panel
        HighSliderLabel                matlab.ui.control.Label
        HighSlider                     matlab.ui.control.Slider
        LowSliderLabel                 matlab.ui.control.Label
        LowSlider                      matlab.ui.control.Slider
        Panel_5                        matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        Panel_6                        matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
        Panel_7                        matlab.ui.container.Panel
        UIAxes5                        matlab.ui.control.UIAxes
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        
        %SELECTED BEAM GEOMETRY
        BeamG;
        %SELECTED RECONSTRUCTION
        Rego;
        %         DialogApp
        %SIMULATORCTLAB
        CallingApp;
        
        %SPECTRAL PARAMETERS APP
        spectralSpectr;
        
        CurrentReco; %Valittu rekonstruktioalgoritmi joka ChosenReconstruction funktion inputti
        CurrentBeamgeom; %Valittu rekonstruktiogeometria joka ChosenBeamgeom funktion inputti
        
        %X-RAY SPECTRUMS MAX AND MIN ENERGIES
        maxE0;
        minE0;
        
        %IMAGING PARAMETERS
        
        %START ANGLE
        minAngle;
        %STEP ANGLE
        stepAngle;
        %STOP ANGLE
        maxAngle;
        %IMAGE VOLUME
        imageVolume;
        %DETECTOR WIDTH
        detectorWidth;
        %DETECTOR ELEMENT SIZE
        detectorElementsize;
        %IMAGE NOISE
        imageNoise;
        %ITERATION NUMBER FOR (LEASTS SQUARES AND TIKHONOV REGULARIZATION)
        iterations;
        %SOURCE-OBJECT-DISTANCE
        SOD;
        %OBJECT-DETECTOR-DISTANCE
        ODD;
        %SELECTED GRADIENT FOR GRADIENT DESCENT ALG
        alpha;
        %REGULARIZARION PARAMETER FOR TIKHONOV REGULARIZATION
        lambda;
        %SCAN TIME
        time;
        %SCAN EXPOSURE
        mA;
        %SCAN TUBE VOLTAGE
        kVp;
        %COPPER FILTER THICKNESS
        Cu;
        %ALUMINUM FILTER THICKNESS
        Al;
        
        %X-RAY SPECTRUM ENERGIES
        E0;
        %X-RAY SPECTRUM INTENSITIES
        I0;
        I;
        
        %MIN AND MAX INTENSITY OF RECONSTRUCTION
        minIntensity;
        maxIntensity;
        
        %PHANTOM
        p;
        %SYSTEM MATRIX
        W;
        
        %PHANTOM MATERIAL DENSITIES
        %IODINE DENSITY
        iodineDensity;
        %WATER DENSITY
        waterDensity;
        %AIR DENSITY
        airDensity;
        %BONE DENSITY
        luuDensity;
        
        %SINOGRAMS
        %SINOGRAM OF PHANTOM
        I_logsum;
        %AIR SINOGRAM
        I_logsum_a;
        %IODINE SINOGRAM
        I_logsum_j;
        %WATER SINOGRAM
        I_logsum_w;
        %BONE SINOGRAM
        I_logsum_l;
        
        %MASKS
        %IODINE MASK
        mask_I;
        %WATER MASK
        mask_water;
        %AIR MASK
        mask_air;
        %BONE MASK
        mask_luu;
        
        %VISUALIZATION
        i;
        j;
        
        %PRE-DEFINED HIGH & LOW CONTRAST OF THE RECONSTRUCTION
        HC=5000;
        LC=-5000;
        
        %RECONSTRUCTED IMAGE
        Reconstruction;
        
        %NUMBER OF BINS IN SPECTRAL IMAGING
        bin;
        %BIN SIZE
        binSize;
        
        %SPECTRAL IMAGE
        spectralIm;
        %MAX SPECTRAL IMAGE NUMBER
        spectLength;
        %
        energyWindows;
        
        %LEFT AND RIGHT CHECK BOXES
        windowSelection1;
        windowSelection2;
        
        recSpin;
        spin;
        
        %         %TUBE VOLTAGE
        %         voltage;
        
        %MIN AND MAX INTENSITIES
        minI;
        maxI;
        
        minInt;
        maxInt
        
        %SPECTRA TOOL
        spectr;


    end
    
    methods (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     FUNCTION TO TRANSFER CHOSEN SPECTRUM PARAMETERS IN MAIN APP(SimulatorCTlab). IS CALLED ON SpectraTool.mlapp.
        %
        %     INPUTS:  q(=spectrum),tubeVoltage,tubeCurrent
        %     OUTPUTS: kVp(tubeVoltage),mA(tubeCurrent),E0(spectrumenergies), I0(Spectrum Intensities), minE0 (spectrum minenergy),
        %              maxE0 (Spectrum max energy)
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function ChosenSpectrum(app,q,tubeVoltage,tubeCurrent,copperFilter, aluminiumFilter)
            
            [app.kVp,app.mA,app.Cu,app.Al,app.E0,app.I0,app.maxE0,app.minE0] = determineSpectrum(q,tubeVoltage,tubeCurrent,copperFilter,aluminiumFilter...
                ,app.UIAxes3,app.ReconstructionalgorithmButton);
            
            %Time for event report
            t = datetime('Now');
            t=datestr(t);
            
            %Report selected parameters in parameter info
            app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Tube current: ',num2str(app.mA),'mA>')};
            app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Tube voltage: ',num2str(app.kVp),'kVp>')};
            app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Al filter thickness: ',num2str(app.Al),'mm>')};
            app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Cu filter thickness: ',num2str(app.Cu),'mm>')};
            app.i = app.i+5;
            
            %Report X-ray spectrum min and max energies in parameter info
            app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectrum Max Energy: ', num2str(app.maxE0),' keV>')};
            app.VisualizationinfoListBox.Items(app.j+1) = {strcat(t, ' <Spectrum Min Energy: ', num2str(app.minE0),' keV>')};
            app.j = app.j+2;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     %FUNCTION TO TRANSFER CHOSEN RECONSTRUCTION METHOD IN MAIN APP. IS CALLED ON ReconstructionAlgo.mlapp
        %
        %     %INPUTS:  reconstruction
        %     %OUTPUTS: CurrentReco
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function ChosenReconstruction(app,reconstruction) %Function to transfer chosen reconstruction method. Is called on ReconstructionAlgo app
            app.CurrentReco=determineReconstruction(reconstruction,app.i,app.ParametersinfoListBox,app.BeamgeometryButton);
            app.i=app.i+1;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %                     FUNCTION TO CANCEL RECONSTRUCTION SELECTION AND CLOSES ReconstructionAlgo.mlapp
        %
        %                     INPUTS: -
        %                     OUTPUTS: -
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function CancelReconstructionSelection(app)
            
            %Time for event report
            t = datetime('Now');
            t=datestr(t);
            %Report event in parameters info
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Reconstruction algorithm setting canceled...>')};
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %           FUNCTION TO TRANSFER CHOSEN BEAM GEOMETRY IN MAIN APP. IS CALLED ON BeamGeometry.mlapp
        %
        %           INPUTS:  beamgeometry
        %           OUTPUTS: CurrentBeamgeom == beamgeometry
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function ChosenBeamgeom(app,beamgeometry) %Function to transfer chosen beam geometry. Is called on BeamGeometry app
            
            app.CurrentBeamgeom=beamgeometry;
            
            %Time for event
            t = datetime('Now');
            t=datestr(t);
            
            %Report selected Reconstruction geometry in parameters info
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Reconstruction geometry: ',app.CurrentBeamgeom,'>')};
            
            %Enable ImagingparametersButton
            app.ImagingparametersButton.Enable = 'on';
            %UnEnable SpectralparametersButton
            app.SpectralparametersButton.Enable = 'off';
            %UnEnable SpectralreconstructionButton
            app.SpectralreconstructionButton.Enable ='off';
            app.i=app.i+1;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %                          CANCEL BEAM GEOMETRY SELECTION AND CLOSES BeamGeometry.mlapp
        %
        %                          INPUTS: -
        %                          OUTPUTS: -
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function CancelBeamgeomSelection(app)
            t = datetime('Now');
            t=datestr(t);
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Reconstruction geometry setting canceled...>')};
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %         FUNCTION TO TRANSFER CHOSEN RECONSTRUCTION PARAMETERS IN MAIN APP. IS CALLED ON ImagingParameters.mlapp
        %
        %         INPUTS: max,min,step,imgVol,detWidth,detElsize,SOD,ODD,noise,iterations,gradient,scanTime,regParam,BG,R
        %         OUTPUTS: maxAngle, minAngle, stepAngle, imageVolume, detectorWidth, detectorElementsize, SOD, ODD, imageNoise,
        %                  iterations, alpha (gradient of gradient descent), time (scantime), lambda,
        %                  (ragularization param. of Tikhonov
        %                  regularization), CurrentBeamgeom, CurrentReco
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function ChosenImagingParameters(app,max,min,step,imgVol,detWidth,detElsize,SOD,ODD,noise,iterations,gradient,scanTime,regParam,BG,R)
            
            
            app.minAngle=min;app.stepAngle=step;app.maxAngle=max;
            app.imageVolume=imgVol;app.detectorWidth=detWidth;app.detectorElementsize=detElsize;
            app.imageNoise=noise;app.iterations=iterations;app.alpha=gradient;
            app.lambda=regParam;app.time=scanTime;app.SOD=SOD;
            app.ODD=ODD;app.CurrentBeamgeom=BG;app.CurrentReco=R;
            
            
            %Case --> Beam geometry == Parallel (app.BeamG == Parallel)
            if (strcmp(app.CurrentBeamgeom,'Parallel')==1)
                
                %Time
                t = datetime('Now');
                t=datestr(t);
                
                %Enable BeamgeometryButton & ScanButton
                app.BeamgeometryButton.Enable ='on';
                app.ScanButton.Enable = 'on';
                
                %Case --> Filtered backprojection (FBP)
                if (strcmp(app.CurrentReco,'Filtered backprojection (FBP)')==1)
                    %Report selected parameters in GUI's parameters info
                    app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Min angle:', num2str(app.minAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Step:', num2str(app.stepAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Max angle:', num2str(app.maxAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Image volume:', num2str(app.imageVolume),'>')};
                    app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Detector width:', num2str(app.detectorWidth),'>')};
                    app.ParametersinfoListBox.Items(app.i+5) = {strcat(t, ' <Detector element size:', num2str(app.detectorElementsize),'>')};
                    app.ParametersinfoListBox.Items(app.i+6) = {strcat(t, ' <Image noise:', num2str(app.imageNoise),'>')};
                    app.ParametersinfoListBox.Items(app.i+7) = {strcat(t, ' <Scan time:', num2str(app.time),'>')};
                    app.i=app.i+7;
                    
                    %Case --> Least squares
                elseif (strcmp(app.CurrentReco,'Least squares')==1)
                    %Report selected parameters in GUI's parameters info
                    app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Min angle:', num2str(app.minAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Step:', num2str(app.stepAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Max angle:', num2str(app.maxAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Image volume:', num2str(app.imageVolume),'>')};
                    app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Detector width:', num2str(app.detectorWidth),'>')};
                    app.ParametersinfoListBox.Items(app.i+5) = {strcat(t, ' <Detector element size:', num2str(app.detectorElementsize),'>')};
                    app.ParametersinfoListBox.Items(app.i+6) = {strcat(t, ' <Image noise:', num2str(app.imageNoise),'>')};
                    app.ParametersinfoListBox.Items(app.i+7) = {strcat(t, ' <Iterations:', num2str(app.iterations),'>')};
                    app.ParametersinfoListBox.Items(app.i+8) = {strcat(t, ' <Scan time:', num2str(app.time),'>')};
                    app.i=app.i+8;
                    
                    %Case --> Tikhonov Regularization
                elseif (strcmp(app.CurrentReco,'Tikhonov Regularization')==1)
                    %Report selected parameters in GUI's parameters info
                    app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Min angle:', num2str(app.minAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Step:', num2str(app.stepAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Max angle:', num2str(app.maxAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Image volume:', num2str(app.imageVolume),'>')};
                    app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Detector width:', num2str(app.detectorWidth),'>')};
                    app.ParametersinfoListBox.Items(app.i+5) = {strcat(t, ' <Detector element size:', num2str(app.detectorElementsize),'>')};
                    app.ParametersinfoListBox.Items(app.i+6) = {strcat(t, ' <Image noise:', num2str(app.imageNoise),'>')};
                    app.ParametersinfoListBox.Items(app.i+7) = {strcat(t, ' <Scan time:', num2str(app.time),'>')};
                    app.ParametersinfoListBox.Items(app.i+8) = {strcat(t, ' <Regularization parameter:', num2str(app.alpha),'>')};
                    app.i=app.i+8;
                end
                
                %Case --> Beam geometry == Fanflat (app.BeamG == Fanflat)
            elseif (strcmp(app.CurrentBeamgeom,'Fanflat')==1)
                
                %Time
                t = datetime('Now');
                t=datestr(t);
                
                %Enable BeamgeometryButton & ScanButton
                app.BeamgeometryButton.Enable ='on';
                app.ScanButton.Enable = 'on';
                
                %Case --> Filtered backprojection (FBP)
                if (strcmp(app.CurrentReco,'Filtered backprojection (FBP)')==1)
                    %Report selected parameters in GUI's parameters info
                    app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Min angle:', num2str(app.minAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Step:', num2str(app.stepAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Max angle:', num2str(app.maxAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Image volume:', num2str(app.imageVolume),'>')};
                    app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Detector width:', num2str(app.detectorWidth),'>')};
                    app.ParametersinfoListBox.Items(app.i+5) = {strcat(t, ' <Detector element size:', num2str(app.detectorElementsize),'>')};
                    app.ParametersinfoListBox.Items(app.i+6) = {strcat(t, ' <SOD:', num2str(app.SOD),'>')};
                    app.ParametersinfoListBox.Items(app.i+7) = {strcat(t, ' <ODD:', num2str(app.ODD),'>')};
                    app.ParametersinfoListBox.Items(app.i+8) = {strcat(t, ' <Image noise:', num2str(app.imageNoise),'>')};
                    app.ParametersinfoListBox.Items(app.i+9) = {strcat(t, ' <Scan time:', num2str(app.time),'>')};
                    app.i=app.i+9;
                    
                    %Case --> Least squares
                elseif (strcmp(app.CurrentReco,'Least squares')==1)
                    %Report selected parameters in GUI's parameters info
                    app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Min angle:', num2str(app.minAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Step:', num2str(app.stepAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Max angle:', num2str(app.maxAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Image volume:', num2str(app.imageVolume),'>')};
                    app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Detector width:', num2str(app.detectorWidth),'>')};
                    app.ParametersinfoListBox.Items(app.i+5) = {strcat(t, ' <Detector element size:', num2str(app.detectorElementsize),'>')};
                    app.ParametersinfoListBox.Items(app.i+6) = {strcat(t, ' <SOD:', num2str(app.SOD),'>')};
                    app.ParametersinfoListBox.Items(app.i+7) = {strcat(t, ' <ODD:', num2str(app.ODD),'>')};
                    app.ParametersinfoListBox.Items(app.i+8) = {strcat(t, ' <Image noise:', num2str(app.imageNoise),'>')};
                    app.ParametersinfoListBox.Items(app.i+9) = {strcat(t, ' <Iterations:', num2str(app.iterations),'>')};
                    app.ParametersinfoListBox.Items(app.i+10) = {strcat(t, ' <Scan time:', num2str(app.time),'>')};
                    app.i=app.i+10;
                    
                    %Case --> Tikhonov Regularization
                elseif (strcmp(app.CurrentReco,'Tikhonov Regularization')==1)
                    %Report selected parameters in GUI's parameters info
                    app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Min angle:', num2str(app.minAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Step:', num2str(app.stepAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Max angle:', num2str(app.maxAngle),'>')};
                    app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Image volume:', num2str(app.imageVolume),'>')};
                    app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Detector width:', num2str(app.detectorWidth),'>')};
                    app.ParametersinfoListBox.Items(app.i+5) = {strcat(t, ' <Detector element size:', num2str(app.detectorElementsize),'>')};
                    app.ParametersinfoListBox.Items(app.i+6) = {strcat(t, ' <SOD:', num2str(app.SOD),'>')};
                    app.ParametersinfoListBox.Items(app.i+7) = {strcat(t, ' <ODD:', num2str(app.ODD),'>')};
                    app.ParametersinfoListBox.Items(app.i+8) = {strcat(t, ' <Image noise:', num2str(app.imageNoise),'>')};
                    app.ParametersinfoListBox.Items(app.i+9) = {strcat(t, ' <Scan time:', num2str(app.time),'>')};
                    app.ParametersinfoListBox.Items(app.i+10) = {strcat(t, ' <Regularization parameter:', num2str(app.alpha),'>')};
                    app.i=app.i+10;
                    
                end
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %           FUNCTION TO TRANSFER CHOSEN SPECTRAL PARAMETERS IN MAIN APP. IS CALLED ON SpectralParameters.mlapp
        %
        %           INPUTS: bins, binSize, energyWindows
        %           OUTPUTS: bins, binSize, energyWindows
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function ChosenSpectralParameters(app,bins,binSize,energyWindows)
            %Number of bins
            app.bin=bins;
            %Bin size
            app.binSize=binSize;
            %Number of energy windows
            app.energyWindows=energyWindows;
            %Time for event
            t = datetime('Now');
            t=datestr(t);
            %Report selected spectral parameters in GUI's parameters info
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Bin size:', num2str(app.binSize),'keV>')};
            app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Number of bins:', num2str(app.bin),'>')};
            app.i=app.i+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% STARTUP MESSAGE
            message = {'Welcome to CTlab! Start simulation by clicking the Load phantom button'};
            uialert(app.CTlabComputedTomographySimulatorv14UIFigure,message,'Warning',...
                'Icon','info');
            %% STARTUP PARAMETERS
            
            %Original screen size
            origX = 1920;
            origY = 1080;
            screen = get(groot,'Screensize');
            currenX = screen(3);
            currentY=screen(4);
            sizeX = currenX/origX;
            sizeY = currentY/origY;
            
            
            if screen == [1 1 1920 1080]
                %CTlab-App position in computer screen
                app.CTlabComputedTomographySimulatorv14UIFigure.Position = [170 90 1552 937];
            elseif screen == [1 1 1680 1050]
                %CTlab-App position in computer screen
                %                     app.CTlabComputedTomographySimulatorv14UIFigure.Position = [170*sizeX 90*sizeY 1552*sizeX 937*sizeY];
                app.CTlabComputedTomographySimulatorv14UIFigure.Position = [170*sizeX 90*sizeY 1552*sizeX 937*sizeY];
            elseif screen == [1 1 1600 900]
                %CTlab-App position in computer screen
                app.CTlabComputedTomographySimulatorv14UIFigure.Position = [170*sizeX 90*sizeY 1552*sizeX 937*sizeY];
            end
            
            
            %Polychromatic reconstruction window (UIAxes)
            app.UIAxes.XColor = 'w';
            app.UIAxes.YColor = 'w';
            app.UIAxes.Color = [0.19,0.19,0.20];
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            
            %Spectral reconstruction window (UIAxes2)
            app.UIAxes2.XColor = 'w';
            app.UIAxes2.YColor = 'w';
            app.UIAxes2.Color = [0.19,0.19,0.20];
            app.UIAxes2.XLabel.String = '';
            app.UIAxes2.YLabel.String = '';
            
            %X-ray spectrum figure (UIAxes3)
            app.UIAxes3.Color = [0.19,0.19,0.20];
            
            %HU-value figure (UIAxes4)
            app.UIAxes4.Color = [0.19,0.19,0.20];
            
            %Lineprofile figure (UIAxes5)
            app.UIAxes5.Color = [0.19,0.19,0.20];
            
            %Start simulation panel (UIAxes6)
            app.UIAxes6.XColor = 'w';
            app.UIAxes6.YColor = 'w';
            app.UIAxes6.Color = [0.19,0.19,0.20];
            app.UIAxes6.XLabel.String = '';
            app.UIAxes6.YLabel.String = '';
            app.UIAxes6.Title.Visible = 'off';
            
            %Lamp
            app.ReadyLampLabel.Visible = 'on';
            
            %Starting time
            t=datetime;
            t=datestr(t);
            
            %Start up messages reported in Parameters info
            app.i=1;
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Initializing Simulator...>')};
            app.i=2;
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Simulator Initialization Succeed>')};
            app.i=3;
            app.ParametersinfoListBox.Items(app.i) = {strcat(t, ' <Welcome to CTlab!>')};
            
            %Unenabled buttons
            app.j=1;
            %             app.SpectralparametersButton.Enable = 'off';
            %             app.SpectralreconstructionButton.Enable ='off';
            %             app.ReconstructionalgorithmButton.Enable = 'off';
            %             app.ImagingparametersButton.Enable = 'off';
            %             app.BeamgeometryButton.Enable ='off';
            %             app.ScanButton.Enable = 'off';
            %             app.VisualizeprojectionsButton.Enable = 'off';
            %             app.ReconstructionButton.Enable = 'off';
            %             app.SelectdatapointsButton.Enable = 'off';
            %             app.LoadspectrumButton.Enable = 'off';
            %             app.GeneratespectrumButton.Enable = 'off';
            %             app.SaveimageButton.Enable = 'off';
            %             app.ClearimageButton.Enable = 'off';
            %             app.LeftCheckBox.Enable = 'off';
            %             app.RightCheckBox.Enable = 'off';
            %             app.Spinner.Enable = 'off';
            
            app.SpectralparametersButton.Enable = 'off';
            app.SpectralreconstructionButton.Enable ='off';
            app.ReconstructionalgorithmButton.Enable = 'off';
            app.ImagingparametersButton.Enable = 'off';
            app.BeamgeometryButton.Enable ='off';
            app.ScanButton.Enable = 'off';
            app.VisualizeprojectionsButton.Enable = 'off';
            app.ReconstructionButton.Enable = 'off';
            app.SelectdatapointsButton.Enable = 'off';
            app.LoadspectrumButton.Enable = 'off';
            app.GeneratespectrumButton.Enable = 'off';
            app.SaveimageButton.Enable = 'off';
            app.ClearimageButton.Enable = 'off';
            app.LeftCheckBox.Enable = 'off';
            app.RightCheckBox.Enable = 'off';
            app.Spinner.Enable = 'off';
            
            %Adjust toolbars of the UIAxes unvisible
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes2.Toolbar.Visible = 'off';
            app.UIAxes3.Toolbar.Visible = 'off';
            app.UIAxes4.Toolbar.Visible = 'off';
            app.UIAxes5.Toolbar.Visible = 'off';
            app.UIAxes6.Toolbar.Visible = 'off';
            
            %Unenable sliders
            app.HighSlider.Enable = 'off';
            app.LowSlider.Enable = 'off';
            
        end

        % Button pushed function: GeneratespectrumButton
        function GeneratespectrumButtonPushed(app, event)
            
            %OPEN SPECTRA TOOL
            app.spectr = spectraTool_v_1_4(app);
            
            %Unenabled buttons
            app.LoadspectrumButton.Enable = 'off';
            app.GeneratespectrumButton.Enable = 'off';
            
        end

        % Button pushed function: LoadspectrumButton
        function LoadspectrumButtonPushed(app, event)
            
            %LOAD THE PRE-DEFINED X-RAY SPECTRUM FOR VIRTUAL SCAN
            %(Vizualized in SPECTRUM figure)
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'off';
            %             app.ReadyLampLabel.Text = 'Loading...'
            
            %Select the PRE-DEFINED X-ray spectrum datafile for virtual CT scan
            [rawname,rawpath] = uigetfile({'.mat'},'Select spectrum data');
            fullname = [rawpath rawname];
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %If user press cancel button
            if isequal(rawname,0)
                %Report the event to the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectrum setting canceled...>')};
                app.j=app.j+1;
                app.ReadyLampLabel.Text = '';
                app.ReadyLamp.Color = 'Green';
                pause(1);
                app.ReadyLampLabel.Visible = 'off';
                
                %If user press OK button
            else
                %Visualize spectrum in the SPECTRUM figure of the panel
                [app.I0, app.E0, app.minE0, app.maxE0, app.kVp, app.mA,app.Al,app.Cu] = polySpectra(app.UIAxes3,rawname,fullname);
                %Report the path of the spectrum file to the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <', ['User selected: ', fullfile(rawname,rawpath)],'>')};
                %Report selected parameters in parameter info
                app.ParametersinfoListBox.Items(app.i+1) = {strcat(t, ' <Tube current: ',num2str(app.mA),'mA>')};
                app.ParametersinfoListBox.Items(app.i+2) = {strcat(t, ' <Tube voltage: ',num2str(app.kVp),'kVp>')};
                app.ParametersinfoListBox.Items(app.i+3) = {strcat(t, ' <Al filter thickness: ',num2str(app.Al),'mm>')};
                app.ParametersinfoListBox.Items(app.i+4) = {strcat(t, ' <Cu filter thickness: ',num2str(app.Cu),'mm>')};
                app.i = app.i+5;
                %Report the min and max energies of the spectrum  spectrum file to the user
                maxE0text = num2str(app.maxE0);
                minE0text = num2str(app.minE0);
                app.VisualizationinfoListBox.Items(app.j+1) = {strcat(t, ' <Spectrum Max Energy: ', maxE0text,' keV>')};
                app.VisualizationinfoListBox.Items(app.j+2) = {strcat(t, ' <Spectrum Min Energy: ', minE0text,' keV>')};
                app.j=app.j+3;
                %Title of the SPECTRUM figure
                app.UIAxes3.Title.Visible = 'on';
                app.UIAxes3.Title.String = 'X-RAY SPECTRUM';
                %Change lamp text and color when process is ready
                app.ReadyLampLabel.Text = 'Ready';
                app.ReadyLamp.Color = 'Green';
                pause(1);
                app.ReadyLampLabel.Visible = 'on';
                %Enable reconstruction button
                app.ReconstructionalgorithmButton.Enable = 'on';
                app.LoadspectrumButton.Enable = 'off';
                app.GeneratespectrumButton.Enable = 'off';
                
            end
            
        end

        % Button pushed function: BeamgeometryButton
        function BeamgeometryButtonPushed(app, event)
            
            %CHOOSE BEAM GEOMETRY WHICH YOU WANT TO USE FOR VIRTUAL
            %MEASUREMENT
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'off';
            
            %Open the beam gometry selection app (beamGeometry.mlapp)
            BeamGeometry_v_1_4(app);
            
            %Change lamp text and color when the beam geometry selection is
            %ready
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = 'Green';
            pause(1);
            app.ReadyLampLabel.Visible = 'on';
            
            
        end

        % Button pushed function: ReconstructionalgorithmButton
        function ReconstructionalgorithmButtonPushed(app, event)
            
            %CHOOSE RECONSTRUCTION ALGORITHM WHICH YOU WANT TO USE IN
            %VIRTUAL MEASUREMENT
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'off';
            
            %Open the reconstruction algorithm selection app
            %(ReconstructionAlgo.mlapp)
            ReconstructionAlgo_v_1_4(app);
            
            %Change lamp text and color when the beam geometry selection is
            %ready
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = 'Green';
            pause(1);
            app.ReadyLampLabel.Visible = 'on';
            
        end

        % Button pushed function: LoadphantomButton
        function LoadphantomButtonPushed(app, event)
            %START NEW SIMULATION AND LOAD PHANTOM
            fantom=phantom(1024);
            
            %Visualize selected phantom in  UIAxes6 and fit the phantom in
            %the axes
            I = imshow(fantom, 'Parent', app.UIAxes6, ...
                'XData', [0 app.UIAxes6.Position(3)], ...
                'YData', [0 app.UIAxes6.Position(4)]);
            
            % Set limits of axes
            app.UIAxes6.XLim = [0 I.XData(2)];
            app.UIAxes6.YLim = [0 I.YData(2)];
            
            %Histogram of the intensities
            histg = histogram(app.UIAxes4, fantom, 'FaceColor','Black','EdgeColor', 'none');
            histg.FaceColor = [0.50,0.62,0.67];
            histg.FaceAlpha = 0.4;
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Report the event
            app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Shepp-Logan phantom loaded>')};
            app.j=app.j+1;
            
            %Delete the title of figure
            app.UIAxes6.Title.Visible = 'off';
            
            %Enable spectrum and generate spectrum button
            app.LoadspectrumButton.Enable = 'on';
            app.GeneratespectrumButton.Enable = 'on';
            
            %Un-enable left and right check boxes spectrum button
            app.LeftCheckBox.Value = false;
            app.RightCheckBox.Value = false;
            
            %Un-enable other buttons
            app.SelectdatapointsButton.Enable = 'off';
            app.SaveimageButton.Enable = 'off';
            app.ClearimageButton.Enable = 'off';
            app.LeftCheckBox.Enable = 'off';
            app.RightCheckBox.Enable = 'off';
            app.Spinner.Enable = 'off';
            app.SpectralparametersButton.Enable = 'off';
            app.SpectralreconstructionButton.Enable ='off';
            app.HighSlider.Enable = 'off';
            app.LowSlider.Enable = 'off';
            
            %Clear axes
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            
        end

        % Button pushed function: ImagingparametersButton
        function ImagingparametersButtonPushed(app, event)
            
            %OPENS IMAGING PARAMETERS APP FROM WHICH USER CAN SET IMAGING PARAMETERS FOR VIRTUAL
            %SCAN
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'off';
            
            %Open the Imaging parameters selection app
            %(ImagingParameters.mlapp)
            app.CallingApp = ImagingParameters_v_1_4(app);
            
            %Change layout of imaging parameter app according to selected reconstruction method and beamgeometry
            ImagingparamLayout(app.CallingApp,app.CurrentReco,app.CurrentBeamgeom);
            GeomReco(app.CallingApp,app.CurrentReco,app.CurrentBeamgeom);
            
            %Change lamp text and color when the beam geometry selection is
            %ready
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = 'Green';
            pause(1);
            app.ReadyLampLabel.Visible = 'on';
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            
            %PERFORM VIRTUAL CT SCAN AND OBTAIN CT PROJECTIONS FOR DIFFERENT ANGLES
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'on';
            app.ReadyLampLabel.Text = 'Loading...';
            
            %Calculate projections
            [app.p,app.W,app.I_logsum,app.I,app.mask_I,app.mask_water,app.mask_air,app.mask_luu] = calculateProjections(app.CTlabComputedTomographySimulatorv14UIFigure, ...
                app.minAngle,app.stepAngle,app.maxAngle, ...
                app.imageVolume,app.detectorElementsize,...
                app.detectorWidth,app.I0,app.imageNoise, ...
                app.minE0,app.maxE0,app.time,app.CurrentBeamgeom, ...
                app.SOD,app.ODD,app.mA);
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Report the event to the user
            app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Projection data calculated>')};
            
            app.j=app.j+1;
            
            %Change lamp text and color when the CT scan is
            %ready
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = 'Green';
            pause(1);
            app.ReadyLampLabel.Visible = 'on';
            app.ReadyLamp.Color = [0.80,0.80,0.80];
            
            %Enable Reconstruction and Visualize projection button
            app.ReconstructionButton.Enable = 'on';
            app.VisualizeprojectionsButton.Enable = 'on';
            
            message = {'Projections detected successfully!'};
            uialert(app.CTlabComputedTomographySimulatorv14UIFigure,message,'Warning',...
                'Icon','success');
        end

        % Button pushed function: VisualizeprojectionsButton
        function VisualizeprojectionsButtonPushed(app, event)
            
            %VISUALIZE PROJECTIONS
            
            % Visualize projections and fit image in the window 1
            I = imshow(app.I_logsum,[], 'Parent', app.UIAxes, ...
                'XData', [0 app.UIAxes.Position(3)], ...
                'YData', [0 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.UIAxes.Title.String = sprintf('PROJECTION DATA')
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Report the event to the user
            app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Projection data visualized>')};
            
            app.j=app.j+1;
            
            %Adjust the image
            app.UIAxes.Box = 'off';
            app.UIAxes.XColor = 'W';
            app.UIAxes.YColor = 'W';
            app.UIAxes.PlotBoxAspectRatio = [1.01724137931034 1 1];
            
            %Enable "ReconstructionalgorithmButton"
            app.ReconstructionalgorithmButton.Enable = 'on';
        end

        % Button pushed function: ReconstructionButton
        function ReconstructionButtonPushed(app, event)
            
            %CALCULATE RECONSTRUCTIONS
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'on';
            app.ReadyLampLabel.Text = 'Loading...';
            
            
            %Select and calculate reconstruction
            [app.Reconstruction, recoText, app.minI, app.maxI] = reconstructionSelection(app.CTlabComputedTomographySimulatorv14UIFigure,app.CurrentReco,...
                app.I_logsum, app.mask_I,app.mask_water,app.mask_air,app.mask_luu, app.W, app.imageVolume, app.alpha, app.lambda, app.iterations, app.UIAxes, app.UIAxes4);
            
            %Change lamp text and color when the reconstruction calculation is
            %ready
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = 'Green';
            pause(1);
            app.ReadyLampLabel.Visible = 'on';
            app.ReadyLamp.Color = [0.80,0.80,0.80];
            
            
            %Enable and unenable buttons depending on the selected
            %algorithm
            if (strcmp(recoText,'Filtered backprojection (FBP)')==1)
                app.GeneratespectrumButton.Enable = 'off';
                app.LoadspectrumButton.Enable = 'off';
                app.ReconstructionalgorithmButton.Enable = 'off';
                app.ImagingparametersButton.Enable = 'off';
                app.BeamgeometryButton.Enable ='off';
                app.ScanButton.Enable = 'off';
                app.VisualizeprojectionsButton.Enable = 'off';
                app.ReconstructionButton.Enable = 'off';
                app.SpectralparametersButton.Enable = 'on';
                app.SpectralreconstructionButton.Enable ='on';
            else
                app.GeneratespectrumButton.Enable = 'off';
                app.LoadspectrumButton.Enable = 'off';
                app.ReconstructionalgorithmButton.Enable = 'off';
                app.ImagingparametersButton.Enable = 'off';
                app.BeamgeometryButton.Enable ='off';
                app.ScanButton.Enable = 'off';
                app.VisualizeprojectionsButton.Enable = 'off';
                app.ReconstructionButton.Enable = 'off';
            end
            
            %Set slider limits
            app.LowSlider.Limits = [app.minI+app.minI/2; 0];
            app.HighSlider.Limits = [0; app.maxI+app.maxI/2];
            app.LowSlider.Value = app.minI+app.minI/2;
            app.HighSlider.Value = app.maxI+app.maxI/2;
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Report the event to the user
            app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Reconstruction: ',app.CurrentReco,' calculated>')};
            
            
            app.j=app.j+1;
            
            %Enable buttons depending on the selected
            %algorithm
            app.SelectdatapointsButton.Enable = 'on';
            app.SaveimageButton.Enable = 'on';
            app.ClearimageButton.Enable = 'on';
            app.LeftCheckBox.Enable = 'on';
            app.HighSlider.Enable = 'on';
            app.LowSlider.Enable = 'on';
            
        end

        % Button pushed function: SpectralparametersButton
        function SpectralparametersButtonPushed(app, event)
            
            %OPENS SPECTRAL PARAMETERS APP WHERE USER CAN ADJUST SPECTRAL PARAMETERS
            
            %Open the Spectral parameters selection app
            %(SpectralParameters.mlapp)
            app.spectralSpectr = spectralParameters_v_1_4(app);
            
            %Invokes the spectralparams function from the
            %spectralParameters app and sets parameters for it (Spectrum
            %I0, min and max energy)
            SpectralParams(app.spectralSpectr,app.I0,app.minE0,app.maxE0)
            
        end

        % Button pushed function: SpectralreconstructionButton
        function SpectralreconstructionButtonPushed(app, event)
            
            %CALCULATE SPECTRAL RECONSTRUCTIONS
            
            %Change lamp text and color when the process is in progress
            app.ReadyLamp.Color = 'Red';
            app.ReadyLampLabel.Visible = 'on';
            app.ReadyLampLabel.Text = 'Loading...';
            
            %Calculate spectral reconstructions
            [app.spectralIm,app.spectLength,app.minI,app.maxI,kk]=spectralImages(app.CTlabComputedTomographySimulatorv14UIFigure,app.CurrentReco, app.iterations, app.minE0,app.bin,app.maxE0,app.I,app.I0,...
                app.mask_I,app.mask_water,app.mask_air,app.mask_luu,app.W,app.energyWindows,app.UIAxes2,app.UIAxes3,app.UIAxes4);
            
            %Set spinner limits
            app.Spinner.Limits = [1 app.spectLength];
            app.Spinner.Value = app.spectLength;
            app.spin = app.Spinner.Value;
            
            %Min and max intensities of the images
            app.minIntensity = app.minI;
            app.maxIntensity = app.maxI;
            app.minI=app.minI(kk);
            app.maxI=app.maxI(kk);
            
            %Slider limits
            app.LowSlider.Limits = [app.minI+app.minI/2; 0];
            app.HighSlider.Limits = [0; app.maxI+app.maxI/2];
            app.LowSlider.Value = app.minI+app.minI/2;
            app.HighSlider.Value = app.maxI+app.maxI/2;
            
            %Change lamp text and color when the spectral reconstructions are ready
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = 'Green';
            pause(1);
            app.ReadyLampLabel.Visible = 'on';
            app.ReadyLamp.Color = [0.80,0.80,0.80];
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Report the event for the user
            app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectral image reconstruction: ',app.Rego,' calculated>')};
            app.j=app.j+1;
            
            %Enable right check box
            app.RightCheckBox.Enable = 'on';
            app.Spinner.Enable = 'on';
            
            %Unenable buttons
            app.SpectralparametersButton.Enable = 'off';
            app.SpectralreconstructionButton.Enable ='off';
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            
            %SPINNER --> CHANGE SPECTRAL IMAGE IN SPECTRAL RECONSTRUCTION
            %WINDOW
            
            %Spinner Value
            app.spin = event.Value;
            
            %Visualize choosed spectral image in spectral image window
            [app.recSpin,app.minI,app.maxI]=spinner(app.spin,app.spectralIm,app.minIntensity,app.maxIntensity,app.energyWindows,app.I0,app.bin,app.minE0,app.maxE0,app.UIAxes2,app.UIAxes3,app.UIAxes4);
            
            
            %Contrast slider limits for spectral images
            app.LowSlider.Limits = [app.minI+app.minI/2; 0];
            app.HighSlider.Limits = [0; app.maxI+app.maxI/2];
            app.LowSlider.Value = app.minI+app.minI/2;
            app.HighSlider.Value = app.maxI+app.maxI/2;
            
        end

        % Value changing function: LowSlider
        function LowSliderValueChanging(app, event)
            
            %LOW SLIDER --> CHANGE CONTRAST
            
            %Low contrast value
            lowC = event.Value;
            app.LC = lowC;
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Left and Right check boxes
            if app.windowSelection1 + app.windowSelection2 == 2
                
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select just one Reconstruction window>')};
                app.j=app.j+1;
                
                %Left check box choosed
            elseif app.windowSelection1 == 1
                
                %Visualize polychromatic reconstruction and fit image in
                %the polychromatic reconstruction window
                I=imshow(app.Reconstruction,[],'Parent',app.UIAxes, 'XData', [0 app.UIAxes.Position(3)],'YData', [0 app.UIAxes.Position(4)]);
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                hold(app.UIAxes,'on')
                %Visualize polychromatic reconstruction with adjusted
                %intensity and fit image in the window
                I=imshow(app.Reconstruction, [app.LC app.HC],'Parent',app.UIAxes, 'XData', [0 app.UIAxes.Position(3)],'YData', [0 app.UIAxes.Position(4)]);
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                hold(app.UIAxes,'off')
                
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Polychromatic Reconstruction window selected>')};
                app.j=app.j+1;
                
                if app.minI < 0
                    
                    %                     app.LowSlider.Limits = [-5000; app.HC];
                    %                     app.HighSlider.Limits = [app.LC; 5000];
                    app.LowSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.HighSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    h = histogram(app.UIAxes4, app.Reconstruction, 'FaceColor','Black','EdgeColor', 'none');
                    h.FaceColor = [0.50,0.62,0.67];
                    h.FaceAlpha = 0.4;
                    app.UIAxes4.XLim = [app.LC app.HC];
                    
                end
                
                % Right check box choosed
            elseif app.windowSelection2 == 1
                
                %Visualize spectral reconstruction (spinner value) and fit image in
                %the reconstruction window
                I=imshow(app.spectralIm(:,:,app.spin),[],'Parent',app.UIAxes2, 'XData', [0 app.UIAxes2.Position(3)],'YData', [0 app.UIAxes2.Position(4)]);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                hold(app.UIAxes2,'on')
                
                %Visualize spectral reconstruction with adjusted
                %intensity and fit image in the spectral reconstruction window
                I=imshow(app.spectralIm(:,:,app.spin), [app.LC app.HC],'Parent',app.UIAxes2, 'XData', [0 app.UIAxes2.Position(3)],'YData', [0 app.UIAxes2.Position(4)]);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                hold(app.UIAxes2,'off');
                
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectral Reconstruction window selected>')};
                app.j=app.j+1;
                
                %Visualize image intensities in HU intensities figure
                %(fig.4)
                h = histogram(app.UIAxes4, app.spectralIm(:,:,app.spin), 'FaceColor','Black','EdgeColor', 'none');
                h.FaceColor = [0.50,0.62,0.67];
                h.FaceAlpha = 0.4;
                app.UIAxes4.XLim = [app.LC app.HC];
                
                if app.minI < 0
                    
                    %                     app.LowSlider.Limits = [-5000; app.HC];
                    %                     app.HighSlider.Limits = [app.LC; 5000];
                    app.LowSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.HighSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    h = histogram(app.UIAxes4, app.spectralIm(:,:,app.spin), 'FaceColor','Black','EdgeColor', 'none');
                    h.FaceColor = [0.50,0.62,0.67];
                    h.FaceAlpha = 0.4;
                    app.UIAxes4.XLim = [app.LC app.HC];
                    
                end
                
                %Left and right check boxes empty
            else
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select Reconstruction window>')};
                app.j=app.j+1;
            end
            
            
        end

        % Value changing function: HighSlider
        function HighSliderValueChanging(app, event)
            
            %HIGH SLIDER --> CHANGE CONTRAST
            
            highC = event.Value;
            app.HC = highC;
            
            t = datetime('Now');
            t=datestr(t);
            
            
            if app.windowSelection1 + app.windowSelection2 == 2
                
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select just one Reconstruction window>')};
                app.j=app.j+1;
                
            elseif app.windowSelection1 == 1
                
                I=imshow(app.Reconstruction,[],'Parent',app.UIAxes, 'XData', [0 app.UIAxes.Position(3)],'YData', [0 app.UIAxes.Position(4)]);
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                hold(app.UIAxes,'on');
                
                I=imshow(app.Reconstruction, [app.LC app.HC],'Parent',app.UIAxes, 'XData', [0 app.UIAxes.Position(3)],'YData', [0 app.UIAxes.Position(4)]);
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                hold(app.UIAxes,'off')
                
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Polychromatic Reconstruction window selected>')};
                app.j=app.j+1;
                
                
                if app.minI < 0
                    
                    %                     app.LowSlider.Limits = [-5000; app.HC];
                    %                     app.HighSlider.Limits = [app.LC; 5000];
                    app.LowSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.HighSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    h = histogram(app.UIAxes4, app.Reconstruction, 'FaceColor','Black','EdgeColor', 'none');
                    h.FaceColor = [0.50,0.62,0.67];
                    h.FaceAlpha = 0.4;
                    app.UIAxes4.XLim = [app.LC app.HC];
                    
                end
                
            elseif app.windowSelection2 == 1
                I=imshow(app.spectralIm(:,:,app.spin),[],'Parent',app.UIAxes2, 'XData', [0 app.UIAxes2.Position(3)],'YData', [0 app.UIAxes2.Position(4)]);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                hold(app.UIAxes2,'on');
                
                I=imshow(app.spectralIm(:,:,app.spin), [app.LC app.HC],'Parent',app.UIAxes2, 'XData', [0 app.UIAxes2.Position(3)],'YData', [0 app.UIAxes2.Position(4)]);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                hold(app.UIAxes2,'off');
                
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectral Reconstruction window selected>')};
                app.j=app.j+1;
                
                if app.minI < 0
                    
                    %                     app.LowSlider.Limits = [-5000; app.HC];
                    %                     app.HighSlider.Limits = [app.LC; 5000];
                    app.LowSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.HighSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    h = histogram(app.UIAxes4, app.spectralIm(:,:,app.spin), 'FaceColor','Black','EdgeColor', 'none');
                    h.FaceColor = [0.50,0.62,0.67];
                    h.FaceAlpha = 0.4;
                    app.UIAxes4.XLim = [app.LC app.HC];
                    
                end
            else
                
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select Reconstruction window>')};
                app.j=app.j+1;
            end
            
            
        end

        % Value changed function: RightCheckBox
        function RightCheckBoxValueChanged(app, event)
            %RIGHT CHECK BOX SELECTED
            
            %windowSelection2 specify Left check box value which is 0
            %when the check box is unselected and 1 when the box is
            %selected
            app.windowSelection2 = app.RightCheckBox.Value;
            
            %CASE --> Right check box selected
            if app.windowSelection2 == 1
                
                %CASE --> Reconstruction minimum intensity is lower than zero
                if app.minI < 0
                    
                    %Contrast slider limits for reconstruction
                    %                     app.LowSlider.Limits = [app.LC; 0];
                    %                     app.HighSlider.Limits = [0; app.HC];
                    app.LowSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.HighSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.LowSlider.Value = app.minI+app.minI/2;
                    app.HighSlider.Value = app.maxI+app.maxI/2;
                    
                end
            end
            
        end

        % Value changed function: LeftCheckBox
        function LeftCheckBoxValueChanged(app, event)
            %LEFT CHECK BOX SELECTED
            
            %windowSelection1 specify Left check box value which is zero
            %when the check box is unselected and one when the box is
            %selected
            app.windowSelection1 = app.LeftCheckBox.Value;
            
            %CASE --> Left check box selected
            if app.windowSelection1 == 1
                
                %CASE --> Reconstruction minimum intensity is lower than 0
                if app.minInt < 0
                    
                    %Contrast slider limits for reconstruction
                    %                     app.LowSlider.Limits = [app.LC; 0];
                    %                     app.HighSlider.Limits = [0; app.HC];
                    app.LowSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.HighSlider.Limits = [app.minI+app.minI/2; app.maxI+app.maxI/2];
                    app.LowSlider.Value = app.minI+app.minI/2;
                    app.HighSlider.Value = app.maxI+app.maxI/2;
                    
                end
                
            end
            
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            %SAVE THE SELECTED RECONSTRUCTION
            
            %Select file location where the image is saved
            %Set first the starting folder which first opens for the user
            startingFolder = 'C:\'; % Or "pwd" or wherever you want.
            defaultFileName = fullfile(startingFolder, '*.png');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            
            %User clicks cancel
            if baseFileName == 0
                % User clicked the Cancel button.
                return;
            end
            
            %Selected file
            fullFileName = fullfile(folder, baseFileName);
            %Time
            t = datetime('Now');
            t=datestr(t);
            %             img = app.Reconstruction
            %             imwrite(img, fullFileName);
            
            %Left and rigth check boxes selected
            if app.windowSelection1 + app.windowSelection2 == 2
                
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select just one Reconstruction to be saved')};
                app.j=app.j+1;
                
                %Left check box selected
            elseif app.windowSelection1 == 1
                
                %                 img = app.LowSlider.Value < app.Reconstruction < app.HighSlider.Value
                img = app.Reconstruction;
                img=img-min(img(:)); % shift data such that the smallest element of A is 0
                img=img/max(img(:)); % normalize the shifted data to 1
                imwrite(img, fullFileName);
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Polychromatic Reconstruction saved>')}
                app.j=app.j+1;
                
                %Right check box selected
            elseif app.windowSelection2 == 1
                
                %                 spectImg = (app.LowSlider.Value < app.spectralIm(:,:,app.spin) < app.HighSlider.Value);
                spectImg = app.spectralIm(:,:,app.spin)
                spectImg=spectImg-min(spectImg(:)); % shift data such that the smallest element of A is 0
                spectImg=spectImg/max(spectImg(:));
                imwrite(spectImg, fullFileName);
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectral Reconstruction saved>')};
                app.j=app.j+1;
                
                %Check boxes empty
            else
                
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select Reconstruction to be saved>')};
                app.j=app.j+1;
            end
        end

        % Button pushed function: ClearimageButton
        function ClearimageButtonPushed(app, event)
            %CLEAR THE SELECTED IMAGE
            
            %Time
            t = datetime('Now');
            t=datestr(t);
            
            %Left and rigth check boxes selected
            if app.windowSelection1 + app.windowSelection2 == 2
                
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select just one Reconstruction to be cleared')};
                app.j=app.j+1;
                
                %Left check box selected
            elseif app.windowSelection1 == 1
                
                %Clear the polychromatic reconstruction window
                cla(app.UIAxes);
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Polychromatic Reconstruction cleared>')};
                app.j=app.j+1;
                
                %Right check box selected
            elseif app.windowSelection2 == 1
                
                %Clear the spectral reconstruction window
                cla(app.UIAxes2);
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectral Reconstruction cleared>')};
                app.j=app.j+1;
                app.Spinner.Value = 1;
                app.Spinner.Enable = 'off';
                
                %Check boxes empty
            else
                
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select Reconstruction to be cleared>')};
                app.j=app.j+1;
            end
        end

        % Button pushed function: SelectdatapointsButton
        function SelectdatapointsButtonPushed(app, event)
            %SELECT DATA POINTS FOR IMAGE UNIFORMITY ANALYZE
            
            %Time
            t = datetime('Now')
            t=datestr(t)
            
            %Left and rigth check boxes selected
            if app.windowSelection1 + app.windowSelection2 == 2
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select just one Reconstruction window>')}
                app.j=app.j+1;
                
                %Left check box selected
            elseif app.windowSelection1 == 1
                
                %LineProf function calculates the lineprofile of the selected area
                lineProf(app.UIAxes,app.Reconstruction,app.UIAxes5,[0.46,0.64,0.98])
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Polychromatic image Lineprofile calculated>')}
                app.j=app.j+1;
                
                %Right check box selected
            elseif app.windowSelection2 == 1
                lineProf(app.UIAxes2,app.spectralIm(:,:,app.spin), app.UIAxes5,'blue')
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Spectral image Lineprofile calculated>')}
                app.j=app.j+1;
                
                %Check boxes empty
            else
                %Report the event for the user
                app.VisualizationinfoListBox.Items(app.j) = {strcat(t, ' <Select Reconstruction window>')}
                app.j=app.j+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CTlabComputedTomographySimulatorv14UIFigure = uifigure('Visible', 'off');
            app.CTlabComputedTomographySimulatorv14UIFigure.Name = 'CTlab - Computed Tomography Simulator v.1.4';
            app.SIMULATIONCONTROLPANELPanel = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.SIMULATIONCONTROLPANELPanel.Title = '   SIMULATION CONTROL PANEL';
            app.Panel_8 = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.XRayspectrumPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.XRayspectrumPanel.Title = 'X-Ray spectrum';
            app.LoadspectrumButton = uibutton(app.XRayspectrumPanel, 'push');
            app.LoadspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspectrumButtonPushed, true);
            app.LoadspectrumButton.Text = {'Load spectrum'; ''};
            app.GeneratespectrumButton = uibutton(app.XRayspectrumPanel, 'push');
            app.GeneratespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratespectrumButtonPushed, true);
            app.GeneratespectrumButton.Text = 'Generate spectrum';
            app.SetParametersPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.SetParametersPanel.Title = 'Set Parameters';
            app.ImagingparametersButton = uibutton(app.SetParametersPanel, 'push');
            app.ImagingparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImagingparametersButtonPushed, true);
            app.ImagingparametersButton.Text = 'Imaging parameters';
            app.BeamgeometryButton = uibutton(app.SetParametersPanel, 'push');
            app.BeamgeometryButton.ButtonPushedFcn = createCallbackFcn(app, @BeamgeometryButtonPushed, true);
            app.BeamgeometryButton.Text = 'Beam geometry';
            app.PolychromaticreconstructionPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.PolychromaticreconstructionPanel.Title = 'Polychromatic reconstruction';
            app.ReconstructionButton = uibutton(app.PolychromaticreconstructionPanel, 'push');
            app.ReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButtonPushed, true);
            app.ReconstructionButton.Text = '    Reconstruction     ';
            app.ReconstructionalgorithmButton = uibutton(app.PolychromaticreconstructionPanel, 'push');
            app.ReconstructionalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionalgorithmButtonPushed, true);
            app.ReconstructionalgorithmButton.Text = 'Reconstruction algorithm';
            app.ObtainprojectionsPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.ObtainprojectionsPanel.Title = 'Obtain projections';
            app.ScanButton = uibutton(app.ObtainprojectionsPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ImagedataPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.ImagedataPanel.Title = 'Image data';
            app.VisualizeprojectionsButton = uibutton(app.ImagedataPanel, 'push');
            app.VisualizeprojectionsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeprojectionsButtonPushed, true);
            app.VisualizeprojectionsButton.Text = 'Visualize projections';
            app.SpectralreconstructionPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.SpectralreconstructionPanel.Title = 'Spectral reconstruction';
            app.SpectralparametersButton = uibutton(app.SpectralreconstructionPanel, 'push');
            app.SpectralparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralparametersButtonPushed, true);
            app.SpectralparametersButton.Text = 'Spectral parameters';
            app.SpectralreconstructionButton = uibutton(app.SpectralreconstructionPanel, 'push');
            app.SpectralreconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralreconstructionButtonPushed, true);
            app.SpectralreconstructionButton.Text = 'Spectral reconstruction';
            app.LineprofilePanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.LineprofilePanel.Title = 'Lineprofile';
            app.SelectdatapointsButton = uibutton(app.LineprofilePanel, 'push');
            app.SelectdatapointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatapointsButtonPushed, true);
            app.SelectdatapointsButton.Text = 'Select data points';
            app.SelectspectralimagePanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.SelectspectralimagePanel.Title = 'Select spectral image';
            app.Spinner = uispinner(app.SelectspectralimagePanel);
            app.Spinner.Limits = [1 150];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.BasicoperationsPanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.BasicoperationsPanel.Title = 'Basic operations';
            app.SaveimageButton = uibutton(app.BasicoperationsPanel, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.ClearimageButton = uibutton(app.BasicoperationsPanel, 'push');
            app.ClearimageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearimageButtonPushed, true);
            app.ClearimageButton.Text = 'Clear image';
            app.SelectimagePanel = uipanel(app.SIMULATIONCONTROLPANELPanel);
            app.SelectimagePanel.Title = 'Select image';
            app.LeftCheckBox = uicheckbox(app.SelectimagePanel);
            app.LeftCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftCheckBoxValueChanged, true);
            app.LeftCheckBox.Text = 'Left';
            app.RightCheckBox = uicheckbox(app.SelectimagePanel);
            app.RightCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightCheckBoxValueChanged, true);
            app.RightCheckBox.Text = 'Right';
            app.ReadyLampLabel = uilabel(app.SIMULATIONCONTROLPANELPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.SIMULATIONCONTROLPANELPanel);
            app.STARTSIMULATIONPanel = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.STARTSIMULATIONPanel.Title = 'START SIMULATION';
            app.LoadphantomButton = uibutton(app.STARTSIMULATIONPanel, 'push');
            app.LoadphantomButton.ButtonPushedFcn = createCallbackFcn(app, @LoadphantomButtonPushed, true);
            app.LoadphantomButton.Text = 'Load phantom';
            app.UIAxes6 = uiaxes(app.STARTSIMULATIONPanel);
            title(app.UIAxes6, 'Phantom')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XTickLabel = '';
            app.UIAxes6.YTickLabel = '';
            app.Panel = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.ParametersinfoListBoxLabel = uilabel(app.Panel);
            app.ParametersinfoListBoxLabel.Text = 'Parameters info';
            app.ParametersinfoListBox = uilistbox(app.Panel);
            app.ParametersinfoListBox.Items = {};
            app.ParametersinfoListBox.Value = {};
            app.Panel_2 = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'POLYCHROMATIC RECONSTRUCTION')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.Panel_3 = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, 'SPECTRAL RECONSTRUCTION')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.Panel_4 = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.VisualizationinfoListBoxLabel = uilabel(app.Panel_4);
            app.VisualizationinfoListBoxLabel.Text = 'Visualization info';
            app.VisualizationinfoListBox = uilistbox(app.Panel_4);
            app.VisualizationinfoListBox.Items = {};
            app.VisualizationinfoListBox.Value = {};
            app.ContrastPanel = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.ContrastPanel.Title = 'Contrast ';
            app.HighSliderLabel = uilabel(app.ContrastPanel);
            app.HighSliderLabel.Text = 'High ';
            app.HighSlider = uislider(app.ContrastPanel);
            app.HighSlider.Limits = [-5000 5000];
            app.HighSlider.ValueChangingFcn = createCallbackFcn(app, @HighSliderValueChanging, true);
            app.HighSlider.Value = 5000;
            app.LowSliderLabel = uilabel(app.ContrastPanel);
            app.LowSliderLabel.Text = 'Low ';
            app.LowSlider = uislider(app.ContrastPanel);
            app.LowSlider.Limits = [-5000 5000];
            app.LowSlider.ValueChangingFcn = createCallbackFcn(app, @LowSliderValueChanging, true);
            app.LowSlider.Value = -5000;
            app.Panel_5 = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.UIAxes3 = uiaxes(app.Panel_5);
            title(app.UIAxes3, 'SPECTRUM')
            xlabel(app.UIAxes3, 'Energy (keV)')
            ylabel(app.UIAxes3, {'Photons / mAs'; ''})
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 175];
            app.UIAxes3.YLim = [0 100000];
            app.Panel_6 = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.UIAxes4 = uiaxes(app.Panel_6);
            title(app.UIAxes4, 'HU VALUES')
            xlabel(app.UIAxes4, 'Intensities')
            ylabel(app.UIAxes4, 'Pixels')
            zlabel(app.UIAxes4, 'Z')
            app.Panel_7 = uipanel(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.UIAxes5 = uiaxes(app.Panel_7);
            title(app.UIAxes5, 'LINEPROFILE')
            xlabel(app.UIAxes5, 'Pixels')
            ylabel(app.UIAxes5, 'Intensities')
            zlabel(app.UIAxes5, 'Z')
            app.Image = uiimage(app.CTlabComputedTomographySimulatorv14UIFigure);
            app.Image.ImageSource = 'CTlab ´logo1.png';
            % Show the figure after all components are created
            app.CTlabComputedTomographySimulatorv14UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulatorCTlab_1_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CTlabComputedTomographySimulatorv14UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CTlabComputedTomographySimulatorv14UIFigure)
        end
    end
end