classdef ImagingParameters_Mac_version_v_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectScanParametersUIFigure    matlab.ui.Figure
        ParameterselectionPanel         matlab.ui.container.Panel
        MinangleEditFieldLabel          matlab.ui.control.Label
        MinangleEditField               matlab.ui.control.NumericEditField
        StepangleEditFieldLabel         matlab.ui.control.Label
        StepangleEditField              matlab.ui.control.NumericEditField
        MaxangleEditFieldLabel          matlab.ui.control.Label
        MaxangleEditField               matlab.ui.control.NumericEditField
        ImagevolumepixEditFieldLabel    matlab.ui.control.Label
        ImagevolumepixEditField         matlab.ui.control.NumericEditField
        DetectorwidthpixEditFieldLabel  matlab.ui.control.Label
        DetectorwidthpixEditField       matlab.ui.control.NumericEditField
        NoiseEditField                  matlab.ui.control.NumericEditField
        ScantimesEditField              matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        ScantimesEditFieldLabel         matlab.ui.control.Label
        RunscanpreviewButton            matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        ScanpreviewPanel                matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        ParametervisualizationPanel     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp;
        rec;
        beam;
        CurrentBeamgeom; CurrentReco;
        max;
        min;
        step;
        imgvol;
        det_width;
        det_el_size;
        sod;
        odd;
        noise;
        iterations;
        gradient;
        scantime;
        reg_param;
        mA;
    end
    
    methods (Access = public)
        
        function tubeCurr(app,mA)
            app.mAEditField.Value=mA;
        end
        
        function ImagingparamLayout(app,reconstruct,beamgeometr)

            app.rec=reconstruct;
            app.beam=beamgeometr;
            
            
        end
        %
        function GeomReco(app,reconstruct,beamgeometr)
            app.CurrentReco = reconstruct;
            app.CurrentBeamgeom=beamgeometr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulatorCTlab, CurrentBeamgeom, CurrentReco, mA)
            app.SelectScanParametersUIFigure.Position = [430 200 1050,695];
            app.CallingApp = simulatorCTlab;
            
            I=phantom(256);
            set(app.UIAxes4,'XLim',[-150,150],'XDir','reverse')%,'XtickMode','MANUAL','XtickLabelMode','MANUAL','Xticklabel',[-150,-100,-50,0,50,100,150]);
            set(app.UIAxes4,'YLim',[-150,150],'YDir','reverse')%,'YtickMode','MANUAL','YtickLabelMode','MANUAL','Yticklabel',[150,100,50,0,-50,-100,-150]);
            set(app.UIAxes4,'Color',[0 0 0],'TickDir','out');
            
            imshow(I,'Parent',app.UIAxes4,'XData',[-70 70],'YData',[-70 70]);
            
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes2.Toolbar.Visible = 'off';
            app.UIAxes3.Toolbar.Visible = 'off';
            app.UIAxes4.Toolbar.Visible = 'off';
            
        end

        % Value changed function: MaxangleEditField
        function MaxangleEditFieldValueChanged(app, event)
            
            t=app.MinangleEditField.Value:5:app.MaxangleEditField.Value;
            sine=sind(t);
            cosine=cosd(t);
            plot(app.UIAxes,sine(1:1:end),cosine(1:1:end),'w.');
            set(app.UIAxes,'XLim',[-1.5,1.5],'XDir','reverse');
            set(app.UIAxes,'YLim',[-1.5,1.5],'YDir','reverse');
            set(app.UIAxes,'Color',[0 0 0]);
            
        end

        % Value changed function: DetectorwidthpixEditField
        function DetectorwidthpixEditFieldValueChanged(app, event)
            
            app.imgvol = app.ImagevolumepixEditField.Value;
            app.det_width = app.DetectorwidthpixEditField.Value;
            
            I = phantom(app.imgvol);
            
            set(app.UIAxes2,'XLim',[-150,150],'XDir','reverse')%,'XtickMode','MANUAL','XtickLabelMode','MANUAL','Xticklabel',[-150,-100,-50,0,50,100,150]);
            set(app.UIAxes2,'YLim',[-150,150],'YDir','reverse')%,'YtickMode','MANUAL','YtickLabelMode','MANUAL','Yticklabel',[150,100,50,0,-50,-100,-150]);
            set(app.UIAxes2,'Color',[0 0 0],'TickDir','out');
            imshow(I,'Parent',app.UIAxes2,'XData',[-70 70],'YData',[-70 70]);
            
            %Scale factor
            F= 150/app.imgvol;
            
            t1 = hgtransform('Parent',app.UIAxes2);
      
            rectangle('Parent',t1,'Position',[70, -F*app.det_width/2, 20, F*app.det_width],'EdgeColor',[1 1 1]);
            polyin = polyshape([-70 -70 70 70], [F*app.det_width/2 -F*app.det_width/2 -F*app.det_width/2 F*app.det_width/2]);
            plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');

        end

        % Value changed function: NoiseEditField
        function NoiseEditFieldValueChanged(app, event)
            
            set(app.UIAxes3,'XLim',[-150,150],'YLim',[-150,150],'Color',[0 0 0]);
            I = imnoise(phantom(256),'gaussian',app.NoiseEditField.Value,0.05);
            imshow(I,'Parent',app.UIAxes3,'XData',[-150 150],'YData',[-150 150]);
            
        end

        % Button pushed function: RunscanpreviewButton
        function RunscanpreviewButtonPushed(app, event)
            %Read editfield values and store them into variables
            app.max = app.MaxangleEditField.Value;
            app.min = app.MinangleEditField.Value;
            app.step = app.StepangleEditField.Value;
            app.imgvol = app.ImagevolumepixEditField.Value;
            app.det_width = app.DetectorwidthpixEditField.Value;
            app.noise = app.NoiseEditField.Value;
            app.scantime = app.ScantimesEditField.Value;
            I = phantom(app.imgvol);
            
            for angle = app.min:10:app.max
                
                set(app.UIAxes4,'XLim',[-150,150],'XDir','reverse');
                set(app.UIAxes4,'YLim',[-150,150],'YDir','reverse');
                set(app.UIAxes4,'Color',[0 0 0]);
                imshow(I,'Parent',app.UIAxes4,'XData',[-70 70],'YData',[-70 70]);
                
                t1 = hgtransform('Parent',app.UIAxes4);
                F= 150/app.imgvol;
                
                rectangle('Parent',t1,'Position',[70, -F*app.det_width/2, 20, F*app.det_width],'EdgeColor',[1 1 1]);
                polyin = polyshape([-70 -70 70 70], [F*app.det_width/2 -F*app.det_width/2 -F*app.det_width/2 F*app.det_width/2]);
                plot(polyin,'Parent',t1,'FaceColor',[1 1 1],'EdgeColor','y');
                
                t1.Matrix=makehgtform('zrotate',-(deg2rad(angle)));
                
                pause(0.001);
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %Calls ChosenImagingParameters function from main app
            %(simulatorCTlab)
            
            ChosenImagingParameters(app.CallingApp,app.MaxangleEditField.Value,app.MinangleEditField.Value,app.StepangleEditField.Value,...
                app.ImagevolumepixEditField.Value,app.DetectorwidthpixEditField.Value,app.NoiseEditField.Value,...
                app.ScantimesEditField.Value,app.CurrentBeamgeom,app.CurrentReco);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectScanParametersUIFigure = uifigure('Visible', 'off');
            app.SelectScanParametersUIFigure.Name = 'Select Scan Parameters';
            app.ParameterselectionPanel = uipanel(app.SelectScanParametersUIFigure);
            app.ParameterselectionPanel.Title = 'Parameter selection';
            app.MinangleEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.MinangleEditFieldLabel.Text = 'Min angle';
            app.MinangleEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.StepangleEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.StepangleEditFieldLabel.Text = 'Step angle';
            app.StepangleEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.MaxangleEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.MaxangleEditFieldLabel.Text = 'Max angle';
            app.MaxangleEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.MaxangleEditField.ValueChangedFcn = createCallbackFcn(app, @MaxangleEditFieldValueChanged, true);
            app.ImagevolumepixEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.ImagevolumepixEditFieldLabel.Text = 'Image volume (pix.)';
            app.ImagevolumepixEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.DetectorwidthpixEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.DetectorwidthpixEditFieldLabel.Text = 'Detector width (pix.)';
            app.DetectorwidthpixEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.DetectorwidthpixEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorwidthpixEditFieldValueChanged, true);
            app.NoiseEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.ScantimesEditField = uieditfield(app.ParameterselectionPanel, 'numeric');
            app.NoiseEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.ScantimesEditFieldLabel = uilabel(app.ParameterselectionPanel);
            app.ScantimesEditFieldLabel.Text = 'Scan time (s)';
            app.RunscanpreviewButton = uibutton(app.ParameterselectionPanel, 'push');
            app.RunscanpreviewButton.ButtonPushedFcn = createCallbackFcn(app, @RunscanpreviewButtonPushed, true);
            app.RunscanpreviewButton.Text = 'Run scan preview';
            app.OKButton = uibutton(app.ParameterselectionPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ScanpreviewPanel = uipanel(app.SelectScanParametersUIFigure);
            app.ScanpreviewPanel.Title = 'Scan preview';
            app.Panel = uipanel(app.ScanpreviewPanel);
            app.UIAxes4 = uiaxes(app.Panel);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.ParametervisualizationPanel = uipanel(app.SelectScanParametersUIFigure);
            app.ParametervisualizationPanel.Title = 'Parameter visualization';
            app.UIAxes = uiaxes(app.ParametervisualizationPanel);
            title(app.UIAxes, 'Scan angles')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.ParametervisualizationPanel);
            title(app.UIAxes2, 'Detector')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.ParametervisualizationPanel);
            title(app.UIAxes3, 'Noise')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            % Show the figure after all components are created
            app.SelectScanParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagingParameters_Mac_version_v_1_0(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectScanParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectScanParametersUIFigure)
        end
    end
end