classdef spectraTool_Mac_version_v_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateXraySpectraUIFigure  matlab.ui.Figure
        Panel_2                      matlab.ui.container.Panel
        ExposureVoltagePanel         matlab.ui.container.Panel
        kVKnobLabel                  matlab.ui.control.Label
        kVKnob                       matlab.ui.control.Knob
        mALabel                      matlab.ui.control.Label
        mAKnob                       matlab.ui.control.Knob
        FiltrationPanel              matlab.ui.container.Panel
        AluminiummmEditFieldLabel    matlab.ui.control.Label
        AluminiummmEditField         matlab.ui.control.EditField
        CoppermmEditFieldLabel       matlab.ui.control.Label
        CoppermmEditField            matlab.ui.control.EditField
        OperatePanel                 matlab.ui.container.Panel
        GeneratespectrumButton       matlab.ui.control.Button
        SavespectrumButton           matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        spectraAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        spectr;
        q;
        UIAxes3;
        kVp; mA;
        CuFilterThick; AlFilterThick;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulatorCTlab, spectr, UIAxes3)
            %% STARTUP PARAMETERS
            
            
            %Original scren size
            origX = 1920;
            origY = 1080;
            screen = get(groot,'Screensize');
            currenX = screen(3);
            currentY=screen(4);
            sizeX = currenX/origX;
            sizeY = currentY/origY;
            
            
            if screen == [1 1 1920 1080]
                %CTlab-App position in computer screen
                app.GenerateXraySpectraUIFigure.Position = [670 400 558 375];
            elseif screen == [1 1 1680 1050]
                %CTlab-App position in computer screen
                app.GenerateXraySpectraUIFigure.Position = [670*sizeX 400*sizeY 558*sizeX 375*sizeY];
            elseif screen == [1 1 1600 900]
                %CTlab-App position in computer screen
                app.GenerateXraySpectraUIFigure.Position = [670*sizeX 400*sizeY 558*sizeX 375*sizeY];
            end
            
%             %             app.GenerateXraySpectraUIFigure.Position = [500 300 781 442];
%             app.GenerateXraySpectraUIFigure.Position = [670 400 558 375];
            app.spectr = simulatorCTlab;
            app.spectraAxes.Toolbar.Visible = 'off';
            
        end

        % Button pushed function: GeneratespectrumButton
        function GeneratespectrumButtonPushed(app, event)
            % Extract kVp
            app.kVp = app.kVKnob.Value;
            %             display(app.kVp)
            
            % Extract mAs
            app.mA = app.mAKnob.Value;
            
            % Extract mm Cu
            app.CuFilterThick = str2double(app.CoppermmEditField.Value);
            
            % Extract mm Al
            app.AlFilterThick = str2double(app.AluminiummmEditField.Value);
            
            % Generate x-ray spectrum
            app.q = spektrSpectrum(app.kVp,[app.AlFilterThick 0],'TASMICS', 1);
            app.q = spektrBeers(app.q, [29 app.CuFilterThick]);
            app.q = app.mA*app.q;
            
            %Eliminate zero values from vector
            E0=1:1:length(app.q);
            E0=E0(app.q~=0);
            
            %Max/Min value of energy in the spectrum
            maxE0 = max(E0);
            minE0 = min(E0);
            
            plot(app.q,'LineWidth',2, 'Parent',app.spectraAxes);
            
            %String which is displayed in text box
            stringforBox1='Min energy: %d keV';
            stringforBox2='Max energy: %d keV';
            stringforBox1 = sprintf(stringforBox1,minE0);
            stringforBox2 = sprintf(stringforBox2,maxE0);
            %
            limY = max(app.q);
            app.spectraAxes.YLim = [0 limY];
            %
            text(app.spectraAxes,70,limY-limY/10,stringforBox1,'Color','w','FontSize',11);
            text(app.spectraAxes,70,limY-2*limY/10,stringforBox2,'Color','w','FontSize',11);
            
            ChosenSpectrum(app.spectr,app.q, app.kVp, app.mA, app.CuFilterThick, app.AlFilterThick)
            
            app.GeneratespectrumButton.Enable = 'off';
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SavespectrumButton
        function SavespectrumButtonPushed(app, event)
            %Select file location where the image is saved
            %Set first the starting folder which first opens for the user
            startingFolder = 'C:\'; % Or "pwd" or wherever you want.
            %             defaultFileName = fullfile(startingFolder, '*.txt');
            defaultFileName = fullfile(startingFolder, '*.mat');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            
            %Selected file
            fullFileName = fullfile(folder, baseFileName);
            
            %User clicks cancel
            if baseFileName == 0
                % User clicked the Cancel button.
                return;
            end
            
            % Create two columns of data
            Energy  = 1:1:length(app.q);
            Energy=Energy';
            Intensity = app.q;
            
            
            % Create a table with the data and variable names
            %             spectrumLength=length(app.q)+4; spectrumEnergies = length(Energy)+4;
            
            spectra=cell(length(Intensity),2); spectra(:,1)=num2cell(Energy); spectra(:,2)=num2cell(Intensity);
            spectra(length(Intensity)+1,1)=cellstr('kVp'); spectra(length(Intensity)+2,1)=cellstr('mA');
            spectra(length(Intensity)+3,1)=cellstr('Al filter thickness'); spectra(length(Intensity)+4,1)=cellstr('Cu filter thickness');
            spectra(length(Energy)+1,2)=num2cell(app.kVp); spectra(length(Energy)+2,2)=num2cell(app.mA);
            spectra(length(Energy)+3,2)=num2cell(app.AlFilterThick); spectra(length(Energy)+4,2)=num2cell(app.CuFilterThick);
            
            
            save(fullFileName, 'spectra');
            
            %             %             T = table(Energy, Intensity, 'VariableNames', { 'A', 'B'} );
            %             T = table(Energy, Intensity);
            %             T=T.Variables;
            %             T=table(T(:,1),T(:,2));
            %             %             TT = ['', 'tube name:', 'Boone/Fewell', '', 'Model Used::','','TASMICS','','','kVp:',num2str(app.kVp),'', 'kV Ripple:','0','','mm Cu:',num2str(app.CuFilterThick),''...
            %             %                 'mm Al:',num2str(app.AlFilterThick),'','Added Filtration:','','']
            %             %             TTT = ['','','','','','','','','','','','','','']
            %             writetable(T, fullFileName,'Delimiter','\t','WriteRowNames',true, 'WriteVariableNames', 0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateXraySpectraUIFigure = uifigure('Visible', 'off');
            app.GenerateXraySpectraUIFigure.Name = 'Generate X-ray Spectra';
            app.Panel_2 = uipanel(app.GenerateXraySpectraUIFigure);
            app.ExposureVoltagePanel = uipanel(app.Panel_2);
            app.ExposureVoltagePanel.Title = 'Exposure & Voltage';
            app.kVKnobLabel = uilabel(app.ExposureVoltagePanel);
            app.kVKnobLabel.Text = 'kV';
            app.kVKnob = uiknob(app.ExposureVoltagePanel, 'continuous');
            app.kVKnob.Limits = [0 140];
            app.kVKnob.MajorTicks = [0 20 40 60 80 100 120 140];
            app.kVKnob.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120', '140'};
            app.kVKnob.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140];
            app.mALabel = uilabel(app.ExposureVoltagePanel);
            app.mALabel.Text = 'mA';
            app.mAKnob = uiknob(app.ExposureVoltagePanel, 'continuous');
            app.mAKnob.Limits = [0 5];
            app.mAKnob.MajorTicks = [0 1 2 3 4 5];
            app.mAKnob.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.FiltrationPanel = uipanel(app.Panel_2);
            app.FiltrationPanel.Title = 'Filtration';
            app.AluminiummmEditFieldLabel = uilabel(app.FiltrationPanel);
            app.AluminiummmEditFieldLabel.Text = 'Aluminium (mm)';
            app.AluminiummmEditField = uieditfield(app.FiltrationPanel, 'text');
            app.AluminiummmEditField.Value = '0.0';
            app.CoppermmEditFieldLabel = uilabel(app.FiltrationPanel);
            app.CoppermmEditFieldLabel.Text = 'Copper (mm)';
            app.CoppermmEditField = uieditfield(app.FiltrationPanel, 'text');
            app.CoppermmEditField.Value = '0.0';
            app.OperatePanel = uipanel(app.Panel_2);
            app.OperatePanel.Title = 'Operate';
            app.GeneratespectrumButton = uibutton(app.OperatePanel, 'push');
            app.GeneratespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratespectrumButtonPushed, true);
            app.GeneratespectrumButton.Text = 'Generate spectrum';
            app.SavespectrumButton = uibutton(app.OperatePanel, 'push');
            app.SavespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SavespectrumButtonPushed, true);
            app.SavespectrumButton.Text = 'Save spectrum';
            app.CloseButton = uibutton(app.OperatePanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel = uipanel(app.Panel_2);
            app.spectraAxes = uiaxes(app.Panel);
            title(app.spectraAxes, 'Spectrum')
            xlabel(app.spectraAxes, {'Photon energy (keV)'; ''})
            ylabel(app.spectraAxes, 'Fluence/mm^2/mAs')
            zlabel(app.spectraAxes, 'Z')
            app.spectraAxes.XLim = [0 175];
            app.spectraAxes.YLim = [0 100000];
            % Show the figure after all components are created
            app.GenerateXraySpectraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spectraTool_Mac_version_v_1_0(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenerateXraySpectraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateXraySpectraUIFigure)
        end
    end
end