classdef spectralParameters_Mac_version_v_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralimagingparametersUIFigure  matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        BinnedSpectrumPanel         matlab.ui.container.Panel
        spectralspectraAxes         matlab.ui.control.UIAxes
        BinsettingsPanel            matlab.ui.container.Panel
        BinsizekeVEditFieldLabel    matlab.ui.control.Label
        BinsizekeVEditField         matlab.ui.control.EditField
        NumberofBinsEditFieldLabel  matlab.ui.control.Label
        NumberofBinsEditField       matlab.ui.control.EditField
        OperatePanel                matlab.ui.container.Panel
        OkButton                    matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        Bins;
        sizeBin;
        minE;maxE;
        q;
        spectralSpectr;
        energyWindows;
    end
    
    methods (Access = public)
        
        function SpectralParams(app,q,minE,maxE)
            app.q=q;
            app.minE=minE;
            app.maxE=maxE;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulatorCTlab, spectralSpectr, q)
            %% STARTUP PARAMETERS
            
            
            %Original scren size
            origX = 1920;
            origY = 1080;
            screen = get(groot,'Screensize');
            currenX = screen(3);
            currentY=screen(4);
            sizeX = currenX/origX;
            sizeY = currentY/origY;
            
            
            if screen == [1 1 1920 1080]
                %CTlab-App position in computer screen
                app.SpectralimagingparametersUIFigure.Position = [640 500 555 215];
            elseif screen == [1 1 1680 1050]
                %CTlab-App position in computer screen
                app.SpectralimagingparametersUIFigure.Position = [640*sizeX 500*sizeY 555*sizeX 215*sizeY];
            elseif screen == [1 1 1600 900]
                %CTlab-App position in computer screen
                app.CTlabComputedTomographySimulatorv13UIFigure.Position = [640*sizeX 500*sizeY 555*sizeX 215*sizeY];
            end
%             
%             app.SpectralimagingparametersUIFigure.Position = [640 500 555 215];
            app.spectralSpectr = simulatorCTlab;
            app.spectralspectraAxes.Toolbar.Visible = 'off';
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Extract Number of Bins
            app.Bins = str2double(app.BinsizekeVEditField.Value);
            %             display(app.kVp)
            
            %Extract one bin size
            app.sizeBin = str2double(app.NumberofBinsEditField.Value);
            
            res=mod(app.maxE-app.minE, app.Bins);
            
            k = app.minE:app.Bins:app.maxE;
            kk=length(k)-1;
            
            if res ~= 0
                k = [k max(k)+res];
                kk=length(k)-1;
            end
            
            %Eliminate zero values from vector
            E0=1:1:length(app.q);
            E0=E0(app.q~=0);
            
            %Max/Min value of energy in the spectrum
            maxE0 = max(E0);
            minE0 = min(E0);
            
            
            plot(app.q,'LineWidth',2, 'Parent',app.spectralspectraAxes);
            
            %String which is displayed in text box
            stringforBox1='Min energy: %d keV';
            stringforBox2='Max energy: %d keV';
            stringforBox1 = sprintf(stringforBox1,minE0);
            stringforBox2 = sprintf(stringforBox2,maxE0);
            
            limY = max(app.q);
            app.spectralspectraAxes.YLim = [0 limY];
            
            text(app.spectralspectraAxes,70,limY-limY/10,stringforBox1,'Color','w','FontSize',11);
            text(app.spectralspectraAxes,70,limY-2*limY/10,stringforBox2,'Color','w','FontSize',11);
            
            hold on
            
            for ii=1:kk
                xl=xline(k(ii),':','Parent',app.spectralspectraAxes);
                xl.Color = 'w';
                h.FaceColor = [0.50,0.62,0.67];
                h.FaceAlpha = 0.4;
                hold on
            end
            
            close all
            
            app.energyWindows=k;
            
            ChosenSpectralParameters(app.spectralSpectr,app.Bins, app.sizeBin,app.energyWindows);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralimagingparametersUIFigure = uifigure('Visible', 'off');
            app.SpectralimagingparametersUIFigure.Name = 'Spectral imaging parameters';
            app.Panel = uipanel(app.SpectralimagingparametersUIFigure);
            app.BinnedSpectrumPanel = uipanel(app.Panel);
            app.BinnedSpectrumPanel.Title = 'Binned Spectrum';
            app.spectralspectraAxes = uiaxes(app.BinnedSpectrumPanel);
            xlabel(app.spectralspectraAxes, {'Photon energy (keV)'; ''})
            ylabel(app.spectralspectraAxes, 'Fluence/mm^2/mAs')
            zlabel(app.spectralspectraAxes, 'Z')
            app.spectralspectraAxes.XLim = [0 175];
            app.spectralspectraAxes.YLim = [0 100000];
            app.spectralspectraAxes.XTick = [0 50 100 150];
            app.spectralspectraAxes.XTickLabel = {'0'; '50'; '100'; '150'};
            app.spectralspectraAxes.YTick = [0 50000 100000];
            app.spectralspectraAxes.YTickLabel = {'0'; '5'; '10'};
            app.BinsettingsPanel = uipanel(app.Panel);
            app.BinsettingsPanel.Title = 'Bin settings';
            app.BinsizekeVEditFieldLabel = uilabel(app.BinsettingsPanel);
            app.BinsizekeVEditFieldLabel.Text = 'Bin size (keV)';
            app.BinsizekeVEditField = uieditfield(app.BinsettingsPanel, 'text');
            app.BinsizekeVEditField.Value = '10';
            app.NumberofBinsEditFieldLabel = uilabel(app.BinsettingsPanel);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.BinsettingsPanel, 'text');
            app.NumberofBinsEditField.Value = '10';
            app.OperatePanel = uipanel(app.Panel);
            app.OperatePanel.Title = 'Operate';
            app.OkButton = uibutton(app.OperatePanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CloseButton = uibutton(app.OperatePanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.SpectralimagingparametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spectralParameters_Mac_version_v_1_0(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralimagingparametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralimagingparametersUIFigure)
        end
    end
end